classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        WindowTabGroup                  matlab.ui.container.TabGroup
        ImportingTab                    matlab.ui.container.Tab
        LoadCoilhardcodedButton         matlab.ui.control.Button
        ProceedafterSelectionButton     matlab.ui.control.Button
        SelectSTLsButton                matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        PlanningTab                     matlab.ui.container.Tab
        CoilControlsTabGroup            matlab.ui.container.TabGroup
        ManualTab                       matlab.ui.container.Tab
        ResetTranslationButton_2        matlab.ui.control.Button
        ResetRotationButton_2           matlab.ui.control.Button
        ZAxisRotationpiradsSlider       matlab.ui.control.Slider
        ZAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        YAxisRotationpiradsSlider       matlab.ui.control.Slider
        YAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        XAxisRotationpiradsSlider       matlab.ui.control.Slider
        XAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        ZTranslationcmSlider            matlab.ui.control.Slider
        ZTranslationcmSliderLabel       matlab.ui.control.Label
        YTranslationcmSlider            matlab.ui.control.Slider
        YTranslationcmSliderLabel       matlab.ui.control.Label
        XTranslationcmSlider            matlab.ui.control.Slider
        XTranslationcmSliderLabel       matlab.ui.control.Label
        AutomaticTab                    matlab.ui.container.Tab
        ResetTranslationButton          matlab.ui.control.Button
        mmFromSkinSlider                matlab.ui.control.Slider
        mmFromSkinSliderLabel           matlab.ui.control.Label
        CoilAxisRotationpiradsSlider    matlab.ui.control.Slider
        CoilAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        ResetRotationButton             matlab.ui.control.Button
        UpdateDisplayButton             matlab.ui.control.Button
        PointZValEditField              matlab.ui.control.NumericEditField
        PointYValEditField              matlab.ui.control.NumericEditField
        PointXValEditField              matlab.ui.control.NumericEditField
        UpdatePointButton               matlab.ui.control.Button
        ChosenPointcmLabel              matlab.ui.control.Label
        CrossSectionControlsTabGroup    matlab.ui.container.TabGroup
        XZPlaneTab                      matlab.ui.container.Tab
        RecenterYCoordButton            matlab.ui.control.Button
        BumpDownButton                  matlab.ui.control.Button
        BumpUpButton                    matlab.ui.control.Button
        PlanesYPoscmSlider              matlab.ui.control.Slider
        PlanesYPoscmSliderLabel         matlab.ui.control.Label
        ToggleXZFieldPlaneButton        matlab.ui.control.StateButton
        YZPlaneTab                      matlab.ui.container.Tab
        ToggleYZFieldPlaneButton        matlab.ui.control.StateButton
        PlanesXPoscmSlider              matlab.ui.control.Slider
        PlanesXPoscmSliderLabel         matlab.ui.control.Label
        RecenterXCoordButton            matlab.ui.control.Button
        BumpUpButton_3                  matlab.ui.control.Button
        BumpDownButton_3                matlab.ui.control.Button
        XYPlaneTab                      matlab.ui.container.Tab
        ToggleXYFieldPlaneButton        matlab.ui.control.StateButton
        PlanesZPoscmSlider              matlab.ui.control.Slider
        PlanesZPoscmSliderLabel         matlab.ui.control.Label
        RecenterZCoordButton            matlab.ui.control.Button
        BumpUpButton_2                  matlab.ui.control.Button
        BumpDownButton_2                matlab.ui.control.Button
        TransformationMatrixSwitch      matlab.ui.control.Switch
        TransformationMatrixSwitchLabel  matlab.ui.control.Label
        VisualControlsPanel             matlab.ui.container.Panel
        ZoomSlider                      matlab.ui.control.Slider
        ZoomSliderLabel                 matlab.ui.control.Label
        HeadCompartmentsDropDown        matlab.ui.control.DropDown
        HeadCompartmentsDropDownLabel   matlab.ui.control.Label
        VectorfromcoilSwitch            matlab.ui.control.Switch
        VectorfromcoilSwitchLabel       matlab.ui.control.Label
        VectortocoilSwitch              matlab.ui.control.Switch
        VectortocoilSwitchLabel         matlab.ui.control.Label
        ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel  matlab.ui.container.Panel
        Versionv021alphaLabel           matlab.ui.control.Label
        TransformationMatrixPanel       matlab.ui.container.Panel
        MatrixField44                   matlab.ui.control.NumericEditField
        MatrixField33                   matlab.ui.control.NumericEditField
        MatrixField34                   matlab.ui.control.NumericEditField
        MatrixField24                   matlab.ui.control.NumericEditField
        MatrixField43                   matlab.ui.control.NumericEditField
        MatrixField23                   matlab.ui.control.NumericEditField
        MatrixField14                   matlab.ui.control.NumericEditField
        MatrixField12                   matlab.ui.control.NumericEditField
        MatrixField22                   matlab.ui.control.NumericEditField
        MatrixField32                   matlab.ui.control.NumericEditField
        MatrixField13                   matlab.ui.control.NumericEditField
        MatrixField11                   matlab.ui.control.NumericEditField
        MatrixField42                   matlab.ui.control.NumericEditField
        MatrixField41                   matlab.ui.control.NumericEditField
        MatrixField31                   matlab.ui.control.NumericEditField
        MatrixField21                   matlab.ui.control.NumericEditField
        CrossSectionDisplay             matlab.ui.control.UIAxes
        CoilDisplay                     matlab.ui.control.UIAxes
        ExportingTab                    matlab.ui.container.Tab
    end


    properties (Access = public)
        shiftX % The shift in position for the X-Axis (Coil)
        shiftY % The shift in position for the Y-Axis (Coil)
        shiftZ % The shift in position for the Z-Axis (Coil)
        %P % The points of the coil in cm
        skinPoint % The chosen point on the skin
        directionNVector % Normal direction vector for coil placement
        %Import table
        meshList % Cell array that stores read stl files from the import table
        meshInternalNames % Cell array that stores internal names from the import table
        meshConductivities % Cell array that stores conductivities from the import table
        meshPath % Cell array that stores pathes from the import table
        meshEncTissues % Cell array that stores enclosing tissues from the import table
        meshFileNames % Cell array that stores file names from the import table
    end

    properties (Access = private)
        dcm_obj % Handle for the UIFigure
    end

    methods (Access = public)

        function updateImage(app)
            %%  The main function called to update the UIAxes (with the coil and brain)

            %   rounds the fields in the matrix
            roundmatrixfield(app);

            cla(app.CoilDisplay); %clears the axis

            %   displays the brain
            displaybrain(app);

            %   displays the coil
            displaycoil(app);

            %   displays the plane cross-sections
            displayplanes(app);
        end
    end

    methods (Access = private)

        function addpathes(app)
            %   Ensure that this step works for unix and windows
            s = pwd;
            if(~isunix)
                slash = '\';
            else
                slash = '/';
            end

            %   Setting the pathes
            app_function_path = [s, slash, 'AppFunctions'];
            charge_engine_path = [s, slash, 'ChargeEngine'];
            coil_path = [s, slash, 'Coil'];
            model_path = [s, slash, 'Model'];

            %   Generating the pathes
            addpath(genpath(app_function_path));
            addpath(genpath(charge_engine_path));
            addpath(genpath(coil_path));
            addpath(genpath(model_path));
        end

        function results = roundmatrixfield(app)

            %   Rounds all of the fields in the matrix
            app.MatrixField11.Value = round(app.MatrixField11.Value, 10);
            app.MatrixField12.Value = round(app.MatrixField12.Value, 10);
            app.MatrixField13.Value = round(app.MatrixField13.Value, 10);
            app.MatrixField21.Value = round(app.MatrixField21.Value, 10);
            app.MatrixField22.Value = round(app.MatrixField22.Value, 10);
            app.MatrixField23.Value = round(app.MatrixField23.Value, 10);
            app.MatrixField31.Value = round(app.MatrixField31.Value, 10);
            app.MatrixField32.Value = round(app.MatrixField32.Value, 10);
            app.MatrixField33.Value = round(app.MatrixField33.Value, 10);
            app.MatrixField41.Value = round(app.MatrixField41.Value, 10);
            app.MatrixField42.Value = round(app.MatrixField42.Value, 10);
            app.MatrixField43.Value = round(app.MatrixField43.Value, 10);

        end

        function displaybrain(app)
            %%  The function called to display the brain figure

            %   Assume that dropdown has been populated from internal names
            %   Assume that we are saving internal names as app.meshInternalNames
            %   Assume meshes have been loaded from stl files to app.meshList
            %   Assume app.meshList is in the same order as a table
            tissue_to_plot = app.HeadCompartmentsDropDown.Value;
            ind1 = find(strcmp(app.meshInternalNames,tissue_to_plot));
            t0 = app.meshList{ind1}.ConnectivityList;
            P = app.meshList{ind1}.Points;

            %Creates str
            %graphics stuff
            str.EdgeColor = 'none'; str.FaceColor = [1 0.75 0.65]; str.FaceAlpha = 1.0;

            %final plotting
            bemf2_graphics_base(app, P, t0, str);

            camlight(app.CoilDisplay);

        end
        function [ ] = displaycoil(app)
            %%  The function called to display the coil

            %Loads Coil
            Coil        = load('coilCAD.mat');
            CoilP = Coil.P;

            %   3x3 Rotation Matrix
            rotMatrix = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];

            %   1x3 Translation Matrix
            transMatrix = [app.MatrixField14.Value, app.MatrixField24.Value, app.MatrixField34.Value];

            %   Scale Matrix (m to cm)
            scaleMatrix = [100 0 0;
                0 100 0;
                0 0 100];

            %   Apply the rotations and transformation to the coil
            for j = 1:size(CoilP,1)
                transformedPoint = scaleMatrix * rotMatrix * CoilP(j,:)' + transMatrix';
                Coil.P(j,:) = transformedPoint';
            end

            if (strcmp(app.VectorfromcoilSwitch.Value, 'On'))
                %   display the vector showing the coil direction
                displaycoilnormalvector(app, rotMatrix, transMatrix);
            end

            if (strcmp(app.VectortocoilSwitch.Value, 'On'))
                %   display the vector from the origin to the coil's translation location
                displayvectortocoil(app, transMatrix);
            end

            %Makarov's function used to display the coil
            bemf1_graphics_coil_CAD(app, Coil.P, Coil.t, 1);

        end

        function displayplanes(app)


            %%  Defines the three planes
            %   Getting coords for field planes
            X = app.PlanesXPoscmSlider.Value;  %   YZ Cross-section position, cm
            Y = app.PlanesYPoscmSlider.Value;  %   XZ Cross-section position, cm
            Z = app.PlanesZPoscmSlider.Value;  %   XY Cross-section position, cm

            %%  Defines aspects for field planes
            delta = 2;  %   half plane window, cm
            xmin = X - delta;   % Cross-section left edge
            xmax = X + delta;   % Cross-section right edge
            ymin = Y - delta;   % Cross-section posterior edge
            ymax = Y + delta;   % Cross-section anterior edge
            zmin = Z - delta;   % Cross-section inferior edge
            zmax = Z + delta;   % Cross-section superior edge

            %%  Displays the three field planes

            if (app.ToggleXYFieldPlaneButton.Value == 1)
                patch(app.CoilDisplay, [xmin xmin xmax xmax], [ymin ymax ymax ymin], [Z Z Z Z], 'c', 'FaceAlpha', 0.35); %   Display XY Plane
            end

            if (app.ToggleXZFieldPlaneButton.Value == 1)
                patch(app.CoilDisplay, [xmin xmin xmax xmax], [Y Y Y Y], [zmin zmax zmax zmin], 'c', 'FaceAlpha', 0.35); %   Display XZ Plane
            end

            if (app.ToggleYZFieldPlaneButton.Value == 1)
                patch(app.CoilDisplay, [X X X X], [ymin ymin ymax ymax], [zmin zmax zmax zmin], 'c', 'FaceAlpha', 0.35); %   Display YZ Plane
            end

            %%  Displays the required cross-section

            %   Prep for all of the actual code
            cla(app.CrossSectionDisplay);

            nifti_filepath = 'T1w.nii';
            if exist(nifti_filepath, 'file')
                VT1         = niftiread(nifti_filepath);
                info        = niftiinfo(nifti_filepath);
            end

            %% Assemble the model
            %   model = bemfmm_assembleModel('tissue_index.txt');

            load("CombinedMesh.mat");
            %     May not need any of this
            model.P = P*1e3;
            model.t = t;
            model.normals = normals;
            model.Center = Center;
            model.Area = Area;
            model.Indicator = Indicator;
            model.tissue = {'Skin', 'Skull', 'CSF', 'GM', 'Cerebellum', 'WM', 'Ventricles'};


            switch app.CrossSectionControlsTabGroup.SelectedTab.Title
                case 'XY Plane'

                    % Parameters for plane
                    planeNormal  = [0 0 1];
                    planeCenter  = [X Y Z]*1e1;    % Not fully confident in the unit conversion here but we have cm and I assume we want m
                    planeUp      = [0 1 0];
                    planeHeight  = delta*1e-2;         % Points in cm to mm
                    planeWidth   = delta*1e-2;
                    pointDensity = 300/planeWidth;      %leaving as is
                    obs = bemfmm_makeObsPlane(planeNormal, planeCenter, planeUp, planeHeight, planeWidth, pointDensity);
                    bemplot_2D_niftiCrossSection(app.CrossSectionDisplay, VT1, info, 'xy', Z*1e-2);
                    brighten(app.CrossSectionDisplay, 0.3);
                    bemplot_2D_modelIntersections(app.CrossSectionDisplay, model, obs); %   Check these things


                case 'XZ Plane'

                    % Parameters for plane
                    planeNormal  = [0 1 0];
                    planeCenter  = [X Y Z]*1e1;    % Not fully confident in the unit conversion here but we have cm and I assume we want m
                    planeUp      = [0 0 1];
                    planeHeight  = delta*1e-2;         % Points in cm to mm
                    planeWidth   = delta*1e-2;
                    pointDensity = 300/planeWidth;
                    obs = bemfmm_makeObsPlane(planeNormal, planeCenter, planeUp, planeHeight, planeWidth, pointDensity);
                    bemplot_2D_niftiCrossSection(app.CrossSectionDisplay, VT1, info, 'xz', Y*1e-2);
                    brighten(app.CrossSectionDisplay, 0.3);
                    bemplot_2D_modelIntersections(app.CrossSectionDisplay, model, obs); %   Check these things


                case 'YZ Plane'

                    % Parameters for plane
                    planeNormal  = [1 0 0];
                    planeCenter  = [X Y Z]*1e1;    % Points in cm to mm
                    planeUp      = [0 0 1];
                    planeHeight  = delta*1e-2;         % assuming wanted in m so converting from cm
                    planeWidth   = delta*1e-2;
                    pointDensity = 300/planeWidth;
                    obs = bemfmm_makeObsPlane(planeNormal, planeCenter, planeUp, planeHeight, planeWidth, pointDensity);
                    bemplot_2D_niftiCrossSection(app.CrossSectionDisplay, VT1, info, 'yz', X*1e-2);
                    brighten(app.CrossSectionDisplay, 0.3);
                    bemplot_2D_modelIntersections(app.CrossSectionDisplay, model, obs); %   Check these things

            end
            axis(app.CrossSectionDisplay, 'equal');


        end

        function txt = pointClickUpdateFunction(~,event_obj)
            %   ~           Currently not used (empty)
            %   event_obj   Object containing event data structure
            %   txt         Displayed text when clicking on the point
            pos = get(event_obj, 'Position');
            txt = '';
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %   Adds the pathes for the required scripts
            addpathes(app);

            %   Turns hold on for both displays
            hold(app.CrossSectionDisplay, "on");
            hold(app.CoilDisplay, "on");

%             %   Loads and stores the points of the brain
%             load('CombinedMesh.mat', "P")
%             app.P = P;
% 
%             %   Scale Matrix (m to cm)
%             scaleMatrix = [100 0 0;
%                 0 100 0;
%                 0 0 100];
% 
%             %   Scales the brain's points to cm
%             for j = 1:size(app.P, 1)
%                 scaledPoint = scaleMatrix * app.P(j,:)';
%                 app.P(j,:) = scaledPoint';
%             end

            %sets a few properties of the CoilDisplay axes
            hold(app.CoilDisplay, "on");
            grid(app.CoilDisplay,'on');
            app.CoilDisplay.XLim = [-20, 20];
            app.CoilDisplay.YLim = [-20, 20];
            app.CoilDisplay.ZLim = [-20, 20];


            %   Labels the units
            xlabel(app.CoilDisplay, 'x, cm');
            ylabel(app.CoilDisplay, 'y, cm');
            zlabel(app.CoilDisplay, 'z, cm');

            %   Sets the update function for clicking points on the
            %   CoilDisplay axes
            datacursormode(app.UIFigure, 'on');
            app.dcm_obj = datacursormode(app.UIFigure);
            set(app.dcm_obj,'UpdateFcn',@pointClickUpdateFunction);

%             %   Sets the limits of the plane sliders to the ends of the
%             %   head
%             app.PlanesXPoscmSlider.Limits = [min(app.P(:,1)), max(app.P(:,1))];
%             app.PlanesYPoscmSlider.Limits = [min(app.P(:,2)), max(app.P(:,2))];
%             app.PlanesZPoscmSlider.Limits = [min(app.P(:,3)), max(app.P(:,3))];

            %   displays the initial image:
            %   updateImage(app);
            app.PlanningTab.A

        end

        % Value changed function: MatrixField11, MatrixField12, 
        % ...and 14 other components
        function MatrixFieldValueChanged(app, event)

            %   Update the image
            updateImage(app);

        end

        % Value changed function: XTranslationcmSlider
        function XTranslationcmSliderValueChanged(app, event)
            %   Adds the new x-translation to the already existing
            %   x-translation
            value = app.XTranslationcmSlider.Value;
            app.MatrixField14.Value = app.MatrixField14.Value + value;

            %   Reset the translation slider to be centered at 0
            app.XTranslationcmSlider.Value = 0;

            %   updates the image
            updateImage(app);

        end

        % Value changed function: YTranslationcmSlider
        function YTranslationcmSliderValueChanged(app, event)
            value = app.YTranslationcmSlider.Value;
            app.MatrixField24.Value = app.MatrixField24.Value + value;

            %   Reset the translation slider to be centered at 0
            app.YTranslationcmSlider.Value = 0;

            %   updates the image
            updateImage(app);

        end

        % Value changed function: ZTranslationcmSlider
        function ZTranslationcmSliderValueChanged(app, event)
            value = app.ZTranslationcmSlider.Value;
            app.MatrixField34.Value = app.MatrixField34.Value + value;

            %   Reset the translation slider to be centered at 0
            app.ZTranslationcmSlider.Value = 0;

            %   updates the image
            updateImage(app);

        end

        % Value changed function: XAxisRotationpiradsSlider
        function XAxisRotationpiradsSliderValueChanged(app, event)
            value = app.XAxisRotationpiradsSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.XAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the x-axis
            Rx = [1, 0, 0;
                0, cos(value), sin(value);
                0, -sin(value), cos(value)];    %X's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the x-axis to the current
            %   rotation matrix
            newRot = Rx*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            updateImage(app);

        end

        % Value changed function: YAxisRotationpiradsSlider
        function YAxisRotationpiradsSliderValueChanged(app, event)
            value = app.YAxisRotationpiradsSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.YAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the y-axis
            Ry = [cos(value), 0, -sin(value);
                0, 1, 0;
                sin(value), 0, cos(value)];  %Y's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = Ry*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            updateImage(app);

        end

        % Value changed function: CoilAxisRotationpiradsSlider
        function CoilAxisRotationpiradsSliderValueChanged(app, event)
            value = app.CoilAxisRotationpiradsSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.CoilAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the z-axis
            Rz = [cos(value), -sin(value), 0;
                sin(value), cos(value), 0;
                0, 0, 1];    %Z's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = CurrentRot*Rz;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            updateImage(app);

        end

        % Button pushed function: ResetRotationButton, 
        % ...and 1 other component
        function ResetRotationButtonPushed(app, event)

            %   Restore rotation matrix to identity matrix
            app.MatrixField11.Value = 1;
            app.MatrixField12.Value = 0;
            app.MatrixField13.Value = 0;
            app.MatrixField21.Value = 0;
            app.MatrixField22.Value = 1;
            app.MatrixField23.Value = 0;
            app.MatrixField31.Value = 0;
            app.MatrixField32.Value = 0;
            app.MatrixField33.Value = 1;

            %   Update visuals
            updateImage(app);


        end

        % Value changed function: ZoomSlider
        function ZoomSliderValueChanged(app, event)
            value = app.ZoomSlider.Value;

            app.CoilDisplay.XLim = [-value,value];
            app.CoilDisplay.YLim = [-value,value];
            app.CoilDisplay.ZLim = [-value,value];

        end

        % Button pushed function: ResetTranslationButton, 
        % ...and 1 other component
        function ResetTranslationButtonPushed(app, event)

            %   Restore translation matrix
            app.MatrixField14.Value = 0;
            app.MatrixField24.Value = 0;
            app.MatrixField34.Value = 0;

            %   Update visuals
            updateImage(app);

        end

        % Callback function
        function HeadCompartmentsButtonGroupSelectionChanged(app, event)
            %selectedButton = app.HeadCompartmentsButtonGroup.SelectedObject;

            %   Update visuals
            updateImage(app);

        end

        % Value changed function: VectorfromcoilSwitch, VectortocoilSwitch
        function VectortocoilSwitchValueChanged(app, event)
            %   Update visuals
            updateImage(app);
        end

        % Callback function
        function PresetButton(app, event)
            selectedButton = app.PresetsTestingOnlyButtonGroup.SelectedObject;

            % preset 1
            if(selectedButton == app.ResetButton)
                app.MatrixField11.Value = 1;
                app.MatrixField12.Value = 0;
                app.MatrixField13.Value = 0;
                app.MatrixField14.Value = 0;
                app.MatrixField21.Value = 0;
                app.MatrixField22.Value = 1;
                app.MatrixField23.Value = 0;
                app.MatrixField24.Value = 0;
                app.MatrixField31.Value = 0;
                app.MatrixField32.Value = 0;
                app.MatrixField33.Value = 1;
                app.MatrixField34.Value = 0;
                % preset 2
            elseif(selectedButton == app.Preset2Button)
                app.MatrixField11.Value = 0.1238;
                app.MatrixField12.Value = 0.9286;
                app.MatrixField13.Value = -0.35;
                app.MatrixField14.Value = 5;
                app.MatrixField21.Value = 0.9329;
                app.MatrixField22.Value = -0.2291;
                app.MatrixField23.Value = -0.2779;
                app.MatrixField24.Value = 5;
                app.MatrixField31.Value = -0.3382;
                app.MatrixField32.Value = -0.2921;
                app.MatrixField33.Value = -0.8946;
                app.MatrixField34.Value = 15;
                % preset 3
            elseif(selectedButton == app.Preset3Button)
                app.MatrixField11.Value = -0.8545;
                app.MatrixField12.Value = -0.195;
                app.MatrixField13.Value = -0.4814;
                app.MatrixField14.Value = 10;
                app.MatrixField21.Value = -0.4446;
                app.MatrixField22.Value = 0.7538;
                app.MatrixField23.Value = 0.4839;
                app.MatrixField24.Value = -10;
                app.MatrixField31.Value = 0.2685;
                app.MatrixField32.Value = 0.6275;
                app.MatrixField33.Value = -0.7309;
                app.MatrixField34.Value = 15;
            end

            %   Update visuals
            updateImage(app);

        end

        % Callback function
        function CoilControlTypeSwitchValueChanged(app, event)
            value = app.CoilControlTypeSwitch.Value;
            switch value
                case 'Manual'

                    %   Enables visibility of the translation controls
                    app.XTranslationcmSlider.Visible = "on";
                    app.XTranslationcmSliderLabel.Visible = "on";
                    app.YTranslationcmSlider.Visible = "on";
                    app.YTranslationcmSliderLabel.Visible = "on";
                    app.ZTranslationcmSlider.Visible = "on";
                    app.ZTranslationcmSliderLabel.Visible = "on";
                    app.XAxisRotationpiradsSlider.Visible = "on";
                    app.XAxisRotationpiradsSliderLabel.Visible = "on";
                    app.YAxisRotationpiradsSlider.Visible = "on";
                    app.YAxisRotationpiradsSliderLabel.Visible = "on";
                    app.CoilAxisRotationpiradsSlider.Visible = "on";
                    app.CoilAxisRotationpiradsSliderLabel.Visible = "on";

                    %   Disables visibility of the point controls
                    app.mmFromSkinSlider.Visible = "off";
                    app.mmFromSkinSliderLabel.Visible = "off";
                    app.UpdatePointButton.Visible = "off";
                    app.UpdateDisplayButton.Visible = "off";
                    app.PointXValEditField.Visible = "off";
                    app.PointYValEditField.Visible = "off";
                    app.PointZValEditField.Visible = "off";
                    app.ChosenPointcmLabel.Visible = "off";

                case 'Automatic'

                    %   Disables visibility of the translation controls
                    app.XTranslationcmSlider.Visible = "off";
                    app.XTranslationcmSliderLabel.Visible = "off";
                    app.YTranslationcmSlider.Visible = "off";
                    app.YTranslationcmSliderLabel.Visible = "off";
                    app.ZTranslationcmSlider.Visible = "off";
                    app.ZTranslationcmSliderLabel.Visible = "off";
                    app.XAxisRotationpiradsSlider.Visible = "off";
                    app.XAxisRotationpiradsSliderLabel.Visible = "off";
                    app.YAxisRotationpiradsSlider.Visible = "off";
                    app.YAxisRotationpiradsSliderLabel.Visible = "off";
                    app.CoilAxisRotationpiradsSlider.Visible = "on";
                    app.CoilAxisRotationpiradsSliderLabel.Visible = "on";

                    %   Disables visibility of the point controls
                    app.mmFromSkinSlider.Visible = "on";
                    app.mmFromSkinSliderLabel.Visible = "on";
                    app.UpdatePointButton.Visible = "on";
                    app.UpdateDisplayButton.Visible = "on";
                    app.PointXValEditField.Visible = "on";
                    app.PointYValEditField.Visible = "on";
                    app.PointZValEditField.Visible = "on";
                    app.ChosenPointcmLabel.Visible = "on";
            end
        end

        % Button pushed function: UpdateDisplayButton
        function UpdateDisplayButtonPushed(app, event)

            disp('Display Updated')

            point = [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value] .* 1e-2;


            %   Find centers, normal vectors, and t on the skin
            load('CombinedMesh.mat', 'Center', 'normals', 'Indicator', 'P', 't');


            eligibleCenters = Center(Indicator==1,:);   % Indicator==1 means on the skin
            eligibleNormals = normals(Indicator==1,:);
            eligibleT = t(Indicator==1,:);

            %   Find the index of the closest point (center of triangle) on the skin
            Idx1 = knnsearch(eligibleCenters, point);

            %   Find the normals of chosen triangle and its neighbors and
            %   average them together
            DT = triangulation(eligibleT,P);
            tNeighbor = neighbors(DT);
            trianglesOfInterest = [Idx1 tNeighbor(Idx1,:)];
            normalsOfInterest = eligibleNormals(trianglesOfInterest,:);
            selectedNormal = mean(normalsOfInterest)/norm(mean(normalsOfInterest));
            app.directionNVector = selectedNormal;

            %   Find the distance from the user's point to it's
            %   intersection with the skin
            intersectionDistance = meshsegtrintersection(point, selectedNormal, 1, P, eligibleT);
            if (any(isnan(intersectionDistance)))
                error('Something went terribly wrong');
            end
            intersectionDistance = intersectionDistance(intersectionDistance~=0);


            %   Find the point of intersection with the skin
            app.skinPoint = point + intersectionDistance*selectedNormal;


            %   Translate the coil
            translations = app.skinPoint + app.directionNVector .* app.mmFromSkinSlider.Value * 1e-3;   %mm
            translations = translations .* 1e2;
            app.MatrixField14.Value = translations(1);
            app.MatrixField24.Value = translations(2);
            app.MatrixField34.Value = translations(3);


            %   Rotate the coil to face opposite the selected normal vector
            angle = 0;
            a = [0,0,-1];   % Vector we are starting at
            b = selectedNormal*(-1); % Vector we want
            v = cross(a, b);
            s = norm(cross(a,b)) * sin(angle);
            c = dot(a,b)*cos(angle);
            vCrossMatrix = [0, -v(3), v(2); v(3), 0, -v(1);-v(2), v(1), 0];

            rotationMatrix = eye(3) + vCrossMatrix + vCrossMatrix^2 * 1/(1+c);
            app.MatrixField11.Value = rotationMatrix(1,1);
            app.MatrixField12.Value = rotationMatrix(1,2);
            app.MatrixField13.Value = rotationMatrix(1,3);
            app.MatrixField21.Value = rotationMatrix(2,1);
            app.MatrixField22.Value = rotationMatrix(2,2);
            app.MatrixField23.Value = rotationMatrix(2,3);
            app.MatrixField31.Value = rotationMatrix(3,1);
            app.MatrixField32.Value = rotationMatrix(3,2);
            app.MatrixField33.Value = rotationMatrix(3,3);


            %   Plot the point on the skin and the user-chosen point
            plot3(app.CoilDisplay, point(1), point(2), point(3), 'r*', 'MarkerSize', 5000);
            plot3(app.CoilDisplay, app.skinPoint(1), app.skinPoint(2), app.skinPoint(3), 'm*', 'MarkerSize', 5000);

            %   Update display
            updateImage(app);

        end

        % Button pushed function: UpdatePointButton
        function UpdatePointButtonPushed(app, event)
            %   Updates point param to edit fields
            info_struct = getCursorInfo(app.dcm_obj);
            if isfield(info_struct, 'Position')
                point = info_struct.Position;

                app.PointXValEditField.Value = round(point(1), 3);
                app.PointYValEditField.Value = round(point(2), 3);
                app.PointZValEditField.Value = round(point(3), 3);
            end
        end

        % Value changed function: TransformationMatrixSwitch
        function TransformationMatrixSwitchValueChanged(app, event)
            value = app.TransformationMatrixSwitch.Value;
            switch value
                case 'Off'

                    %   Disables visibility of the Transformation Matrix
                    app.TransformationMatrixPanel.Visible = "off";

                case 'On'

                    %   Enables visibility of the Transformation Matrix
                    app.TransformationMatrixPanel.Visible = "on";
            end

        end

        % Value changed function: ZAxisRotationpiradsSlider
        function ZAxisRotationpiradsSliderValueChanged(app, event)
            value = app.ZAxisRotationpiradsSlider.Value*pi;

            %   Reset the rotation slider to be centered at 0
            app.ZAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the z-axis
            Rz = [cos(value), -sin(value), 0;
                sin(value), cos(value), 0;
                0, 0, 1];    %Z's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = Rz*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            updateImage(app);

        end

        % Value changed function: HeadCompartmentsDropDown
        function HeadCompartmentsDropDownValueChanged(app, event)

            %   Updates the display. The checking is handled in
            %   AppFunctions/displaybrain.m
            updateImage(app);
        end

        % Callback function: PlanesXPoscmSlider, PlanesYPoscmSlider, 
        % ...and 5 other components
        function PlanesZPoscmSliderValueChanged(app, event)

            %   Update the display with the new values
            updateImage(app);

        end

        % Selection change function: CrossSectionControlsTabGroup
        function CrossSectionControlsTabGroupSelectionChanged(app, event)

            %   Update the image
            updateImage(app);
        end

        % Button pushed function: SelectSTLsButton
        function SelectSTLsButtonPushed(app, event)

            %   Save file and path of the opened documents
            [file,path] = uigetfile('*.stl', 'Select one or more files', 'MultiSelect', 'on');

            n_files = numel(cellstr(file));

            %   Initialize cell arrays
            app.meshConductivities = cell(1,n_files);
            app.meshPath = cell(1,n_files);
            app.meshInternalNames = cell(1,n_files);
            app.meshList = cell(1,n_files);
            app.meshEncTissues = cell(1,n_files);
            app.meshFileNames = cell(1,n_files);

            %   Change values in the table
            for k = 1:n_files
                if n_files==1
                    fileOut = file;
                else
                    fileOut = file{k};
                end
            
            %  Parse common tissue name from filename
            %  Expression is the list of tissue names that the function will
            %  search for in the filename. 
            %  More names can be added following the same format
            expression = 'bone|skull|csf|eyes|gm|skin|ventricles|wm';
            %  regexpi: Case-insensitive regex search for a tissue name in fileOut.
            %  lower: forces lowercase output for consistency
            %  regFileOut: Cell array with the tissue name from fileOut
            regFileOut = lower(regexpi(fileOut,expression,'match'));
            %  regFileChar: Char array for use in switch statement
            regFileChar = char(regFileOut);
            %  Switch statement takes regFileChar and assigns default
            %  name, conductivity, and enclosing tissue
            %  This list of cases can be expanded to accomodate more
            %  tissue names
            switch regFileChar
                case 'bone', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %  \/
                case 'skull', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %   /\ Treating bone and skull the same
                case 'csf', nameFile{k} = 'CSF'; conductivity{k} = 1.654 ; enclosingTissue{k} = 'Skull';
                case 'eyes', nameFile{k} = 'Eyes'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';  %  Default conductivity might not be right!!
                case 'gm', nameFile{k} = 'GM'; conductivity{k} = 0.2750; enclosingTissue{k} = 'CSF';
                case 'skin', nameFile{k} = 'Skin'; conductivity{k} = 0.4650; enclosingTissue{k} = 'FreeSpace';
                case 'ventricles', nameFile{k} = 'Ventricles'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';
                case 'wm', nameFile{k} = 'WM'; conductivity{k} = 0.1260; enclosingTissue{k} = 'GM';
            end
            
                if k == 1
                    app.UITable.Data = {k path fileOut nameFile{k} conductivity{k} enclosingTissue{k}};
                else
                    app.UITable.Data = [app.UITable.Data; {k path fileOut nameFile{k} conductivity{k} enclosingTissue{k}}];
                end
            end

            %   Show UI Figure again after it dissapears / uigetfile bug
            figure(app.UIFigure)

        end

        % Button pushed function: ProceedafterSelectionButton
        function ProceedafterSelectionButtonPushed(app, event)

            %   Save data from the table in cell arrays
            for i = 1:size(app.UITable.Data,1)

                app.meshPath{i} = cell2mat(app.UITable.Data(i,2));
                app.meshFileNames{i} = cell2mat(app.UITable.Data(i,3));
                app.meshList{i} = stlread(cell2mat(app.UITable.Data(i,3)));
                app.meshInternalNames{i} = cell2mat(app.UITable.Data(i,4));
                app.meshConductivities{i} = cell2mat(app.UITable.Data(i,5));
                app.meshEncTissues{i} = cell2mat(app.UITable.Data(i,6));

            end

            createTxt(app);
            modelProcessor(app);

            %   Loads and stores the points of the brain
            load('CombinedMesh.mat', 'P')
            %app.P = P;

            %   Scale Matrix (m to cm)
            scaleMatrix = [100 0 0;
                0 100 0;
                0 0 100];

            %   Scales the brain's points to cm
            for j = 1:size(P, 1)
                scaledPoint = scaleMatrix * P(j,:)';
                P(j,:) = scaledPoint';
            end

            %   Sets the limits of the plane sliders to the ends of the
            %   head
            app.PlanesXPoscmSlider.Limits = [min(P(:,1)), max(P(:,1))];
            app.PlanesYPoscmSlider.Limits = [min(P(:,2)), max(P(:,2))];
            app.PlanesZPoscmSlider.Limits = [min(P(:,3)), max(P(:,3))];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.WindowTabGroup = uitabgroup(app.GridLayout);
            app.ImportingTab = uitab(app.WindowTabGroup);
            app.ImportingTab.Title = 'Importing';
            app.UITable = uitable(app.ImportingTab);
            app.UITable.ColumnName = {'Item #'; 'File Path'; 'File Name'; 'Internal Name'; 'Conductivities (S/m)'; 'Enclosing Tissue'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.SelectSTLsButton = uibutton(app.ImportingTab, 'push');
            app.SelectSTLsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSTLsButtonPushed, true);
            app.SelectSTLsButton.Text = 'Select STLs';
            app.ProceedafterSelectionButton = uibutton(app.ImportingTab, 'push');
            app.ProceedafterSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedafterSelectionButtonPushed, true);
            app.ProceedafterSelectionButton.Text = 'Proceed after Selection';
            app.LoadCoilhardcodedButton = uibutton(app.ImportingTab, 'push');
            app.LoadCoilhardcodedButton.Text = 'Load Coil (hard coded)';
            app.PlanningTab = uitab(app.WindowTabGroup);
            app.PlanningTab.Title = 'Planning';
            app.CoilDisplay = uiaxes(app.PlanningTab);
            xlabel(app.CoilDisplay, 'X')
            ylabel(app.CoilDisplay, 'Y')
            zlabel(app.CoilDisplay, 'Z')
            app.CrossSectionDisplay = uiaxes(app.PlanningTab);
            xlabel(app.CrossSectionDisplay, 'X')
            ylabel(app.CrossSectionDisplay, 'Y')
            zlabel(app.CrossSectionDisplay, 'Z')
            app.TransformationMatrixPanel = uipanel(app.PlanningTab);
            app.TransformationMatrixPanel.Title = 'Transformation Matrix';
            app.MatrixField21 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField21.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField31 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField31.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField41.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41.Editable = 'off';
            app.MatrixField42 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField42.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField42.Editable = 'off';
            app.MatrixField11 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField11.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField11.Value = 1;
            app.MatrixField13 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField13.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField32 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField32.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField22.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22.Value = 1;
            app.MatrixField12 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField12.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField14 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField14.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField23 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField23.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField43.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43.Editable = 'off';
            app.MatrixField24 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField24.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField34 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField34.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField33 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField33.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField33.Value = 1;
            app.MatrixField44 = uieditfield(app.TransformationMatrixPanel, 'numeric');
            app.MatrixField44.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField44.Editable = 'off';
            app.MatrixField44.Value = 1;
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel = uipanel(app.PlanningTab);
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel.Title = 'Contributors: Alton Miles, Graham Ornstein, Kseniia Romanova';
            app.Versionv021alphaLabel = uilabel(app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel);
            app.Versionv021alphaLabel.Text = 'Version: v0.2.1-alpha';
            app.VisualControlsPanel = uipanel(app.PlanningTab);
            app.VisualControlsPanel.Title = 'Visual Controls';
            app.VectortocoilSwitchLabel = uilabel(app.VisualControlsPanel);
            app.VectortocoilSwitchLabel.Text = 'Vector to coil';
            app.VectortocoilSwitch = uiswitch(app.VisualControlsPanel, 'slider');
            app.VectortocoilSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.VectortocoilSwitch.Tooltip = {'Toggle a vector from the brain to the coil'};
            app.VectorfromcoilSwitchLabel = uilabel(app.VisualControlsPanel);
            app.VectorfromcoilSwitchLabel.Text = 'Vector from coil';
            app.VectorfromcoilSwitch = uiswitch(app.VisualControlsPanel, 'slider');
            app.VectorfromcoilSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.VectorfromcoilSwitch.Tooltip = {'Toggle a vector showing the coil''s direction'};
            app.VectorfromcoilSwitch.Value = 'On';
            app.HeadCompartmentsDropDownLabel = uilabel(app.VisualControlsPanel);
            app.HeadCompartmentsDropDownLabel.Text = 'Head Compartments';
            app.HeadCompartmentsDropDown = uidropdown(app.VisualControlsPanel);
            app.HeadCompartmentsDropDown.Items = {'Skin', 'Bone', 'CSF', 'GM', 'WM', 'Ventricles', 'Eyes'};
            app.HeadCompartmentsDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadCompartmentsDropDownValueChanged, true);
            app.HeadCompartmentsDropDown.Value = 'GM';
            app.ZoomSliderLabel = uilabel(app.VisualControlsPanel);
            app.ZoomSliderLabel.Text = 'Zoom';
            app.ZoomSlider = uislider(app.VisualControlsPanel);
            app.ZoomSlider.Limits = [1 40];
            app.ZoomSlider.MajorTicks = [1 10 20 30 40];
            app.ZoomSlider.ValueChangedFcn = createCallbackFcn(app, @ZoomSliderValueChanged, true);
            app.ZoomSlider.MinorTicks = [5 15 25 35];
            app.ZoomSlider.Tooltip = {'Change the axis size'};
            app.ZoomSlider.Value = 20;
            app.TransformationMatrixSwitchLabel = uilabel(app.PlanningTab);
            app.TransformationMatrixSwitchLabel.Text = 'Transformation Matrix Switch';
            app.TransformationMatrixSwitch = uiswitch(app.PlanningTab, 'slider');
            app.TransformationMatrixSwitch.ValueChangedFcn = createCallbackFcn(app, @TransformationMatrixSwitchValueChanged, true);
            app.TransformationMatrixSwitch.Value = 'On';
            app.CrossSectionControlsTabGroup = uitabgroup(app.PlanningTab);
            app.CrossSectionControlsTabGroup.SelectionChangedFcn = createCallbackFcn(app, @CrossSectionControlsTabGroupSelectionChanged, true);
            app.XYPlaneTab = uitab(app.CrossSectionControlsTabGroup);
            app.XYPlaneTab.Title = 'XY Plane';
            app.BumpDownButton_2 = uibutton(app.XYPlaneTab, 'push');
            app.BumpDownButton_2.Text = 'Bump Down';
            app.BumpUpButton_2 = uibutton(app.XYPlaneTab, 'push');
            app.BumpUpButton_2.Text = 'Bump Up';
            app.RecenterZCoordButton = uibutton(app.XYPlaneTab, 'push');
            app.RecenterZCoordButton.Text = 'Recenter Z Coord';
            app.PlanesZPoscmSliderLabel = uilabel(app.XYPlaneTab);
            app.PlanesZPoscmSliderLabel.Text = 'Plane''s Z Pos (cm)';
            app.PlanesZPoscmSlider = uislider(app.XYPlaneTab);
            app.PlanesZPoscmSlider.ValueChangedFcn = createCallbackFcn(app, @PlanesZPoscmSliderValueChanged, true);
            app.ToggleXYFieldPlaneButton = uibutton(app.XYPlaneTab, 'state');
            app.ToggleXYFieldPlaneButton.ValueChangedFcn = createCallbackFcn(app, @PlanesZPoscmSliderValueChanged, true);
            app.ToggleXYFieldPlaneButton.Text = 'Toggle XY Field Plane';
            app.YZPlaneTab = uitab(app.CrossSectionControlsTabGroup);
            app.YZPlaneTab.Title = 'YZ Plane';
            app.BumpDownButton_3 = uibutton(app.YZPlaneTab, 'push');
            app.BumpDownButton_3.Text = 'Bump Down';
            app.BumpUpButton_3 = uibutton(app.YZPlaneTab, 'push');
            app.BumpUpButton_3.Text = 'Bump Up';
            app.RecenterXCoordButton = uibutton(app.YZPlaneTab, 'push');
            app.RecenterXCoordButton.Text = 'Recenter X Coord';
            app.PlanesXPoscmSliderLabel = uilabel(app.YZPlaneTab);
            app.PlanesXPoscmSliderLabel.Text = 'Plane''s X Pos (cm)';
            app.PlanesXPoscmSlider = uislider(app.YZPlaneTab);
            app.PlanesXPoscmSlider.ValueChangedFcn = createCallbackFcn(app, @PlanesZPoscmSliderValueChanged, true);
            app.ToggleYZFieldPlaneButton = uibutton(app.YZPlaneTab, 'state');
            app.ToggleYZFieldPlaneButton.ValueChangedFcn = createCallbackFcn(app, @PlanesZPoscmSliderValueChanged, true);
            app.ToggleYZFieldPlaneButton.Text = 'Toggle YZ Field Plane';
            app.XZPlaneTab = uitab(app.CrossSectionControlsTabGroup);
            app.XZPlaneTab.SizeChangedFcn = createCallbackFcn(app, @PlanesZPoscmSliderValueChanged, true);
            app.XZPlaneTab.Title = 'XZ Plane';
            app.ToggleXZFieldPlaneButton = uibutton(app.XZPlaneTab, 'state');
            app.ToggleXZFieldPlaneButton.ValueChangedFcn = createCallbackFcn(app, @PlanesZPoscmSliderValueChanged, true);
            app.ToggleXZFieldPlaneButton.Text = 'Toggle XZ Field Plane';
            app.PlanesYPoscmSliderLabel = uilabel(app.XZPlaneTab);
            app.PlanesYPoscmSliderLabel.Text = 'Plane''s Y Pos (cm)';
            app.PlanesYPoscmSlider = uislider(app.XZPlaneTab);
            app.PlanesYPoscmSlider.ValueChangedFcn = createCallbackFcn(app, @PlanesZPoscmSliderValueChanged, true);
            app.BumpUpButton = uibutton(app.XZPlaneTab, 'push');
            app.BumpUpButton.Text = 'Bump Up';
            app.BumpDownButton = uibutton(app.XZPlaneTab, 'push');
            app.BumpDownButton.Text = 'Bump Down';
            app.RecenterYCoordButton = uibutton(app.XZPlaneTab, 'push');
            app.RecenterYCoordButton.Text = 'Recenter Y Coord';
            app.CoilControlsTabGroup = uitabgroup(app.PlanningTab);
            app.AutomaticTab = uitab(app.CoilControlsTabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.ChosenPointcmLabel = uilabel(app.AutomaticTab);
            app.ChosenPointcmLabel.Text = 'Chosen Point (cm)';
            app.UpdatePointButton = uibutton(app.AutomaticTab, 'push');
            app.UpdatePointButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePointButtonPushed, true);
            app.UpdatePointButton.Tooltip = {'Overwrites the point in the program. Only do this for point on the GM layer'};
            app.UpdatePointButton.Text = 'UpdatePoint';
            app.PointXValEditField = uieditfield(app.AutomaticTab, 'numeric');
            app.PointXValEditField.Tooltip = {'The targeted point''s x-coordinate'};
            app.PointYValEditField = uieditfield(app.AutomaticTab, 'numeric');
            app.PointYValEditField.Tooltip = {'The targeted point''s y-coordinate'};
            app.PointZValEditField = uieditfield(app.AutomaticTab, 'numeric');
            app.PointZValEditField.Tooltip = {'The targeted point''s z-coordinate'};
            app.UpdateDisplayButton = uibutton(app.AutomaticTab, 'push');
            app.UpdateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplayButtonPushed, true);
            app.UpdateDisplayButton.Tooltip = {'Updates the display to reflect the point and the radius values'};
            app.UpdateDisplayButton.Text = 'Update Display';
            app.ResetRotationButton = uibutton(app.AutomaticTab, 'push');
            app.ResetRotationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton.Tooltip = {'Resets the rotation of the coil'};
            app.ResetRotationButton.Text = 'Reset Rotation';
            app.CoilAxisRotationpiradsSliderLabel = uilabel(app.AutomaticTab);
            app.CoilAxisRotationpiradsSliderLabel.Text = 'Coil-Axis Rotation (pi rads)';
            app.CoilAxisRotationpiradsSlider = uislider(app.AutomaticTab);
            app.CoilAxisRotationpiradsSlider.Limits = [-1 1];
            app.CoilAxisRotationpiradsSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.CoilAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.75', '-0.5', '-0.25', '0', '0.25', '0.5', '0.75', '1'};
            app.CoilAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @CoilAxisRotationpiradsSliderValueChanged, true);
            app.CoilAxisRotationpiradsSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.CoilAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the coil''s axis'};
            app.mmFromSkinSliderLabel = uilabel(app.AutomaticTab);
            app.mmFromSkinSliderLabel.Text = 'mm From Skin';
            app.mmFromSkinSlider = uislider(app.AutomaticTab);
            app.mmFromSkinSlider.MajorTicks = [0 20 40 60 80 100];
            app.mmFromSkinSlider.MinorTicks = [5 10 15 25 30 35 45 50 55 65 70 75 85 90 95];
            app.mmFromSkinSlider.Tooltip = {'The distance from the skin for the coil'};
            app.ResetTranslationButton = uibutton(app.AutomaticTab, 'push');
            app.ResetTranslationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTranslationButtonPushed, true);
            app.ResetTranslationButton.Tooltip = {'Resets the translation of the coil'};
            app.ResetTranslationButton.Text = 'Reset Translation';
            app.ManualTab = uitab(app.CoilControlsTabGroup);
            app.ManualTab.Title = 'Manual';
            app.XTranslationcmSliderLabel = uilabel(app.ManualTab);
            app.XTranslationcmSliderLabel.Tooltip = {'The x-component of the coil''s translation'};
            app.XTranslationcmSliderLabel.Text = 'X Translation (cm)';
            app.XTranslationcmSlider = uislider(app.ManualTab);
            app.XTranslationcmSlider.Limits = [-20 20];
            app.XTranslationcmSlider.MajorTicks = [-20 -10 0 10 20];
            app.XTranslationcmSlider.ValueChangedFcn = createCallbackFcn(app, @XTranslationcmSliderValueChanged, true);
            app.XTranslationcmSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.XTranslationcmSlider.Tooltip = {'Applies a translation to the coil along the x-axis'};
            app.YTranslationcmSliderLabel = uilabel(app.ManualTab);
            app.YTranslationcmSliderLabel.Tooltip = {'The y-component of the coil''s translation'};
            app.YTranslationcmSliderLabel.Text = 'Y Translation (cm)';
            app.YTranslationcmSlider = uislider(app.ManualTab);
            app.YTranslationcmSlider.Limits = [-20 20];
            app.YTranslationcmSlider.MajorTicks = [-20 -10 0 10 20];
            app.YTranslationcmSlider.ValueChangedFcn = createCallbackFcn(app, @YTranslationcmSliderValueChanged, true);
            app.YTranslationcmSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.YTranslationcmSlider.Tooltip = {'Applies a translation to the coil along the y-axis'};
            app.ZTranslationcmSliderLabel = uilabel(app.ManualTab);
            app.ZTranslationcmSliderLabel.Tooltip = {'The z-component of the coil''s translation'};
            app.ZTranslationcmSliderLabel.Text = 'Z Translation (cm)';
            app.ZTranslationcmSlider = uislider(app.ManualTab);
            app.ZTranslationcmSlider.Limits = [-20 20];
            app.ZTranslationcmSlider.MajorTicks = [-20 -10 0 10 20];
            app.ZTranslationcmSlider.ValueChangedFcn = createCallbackFcn(app, @ZTranslationcmSliderValueChanged, true);
            app.ZTranslationcmSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.ZTranslationcmSlider.Tooltip = {'Applies a translation to the coil along the z-axis'};
            app.XAxisRotationpiradsSliderLabel = uilabel(app.ManualTab);
            app.XAxisRotationpiradsSliderLabel.Text = 'X-Axis Rotation (pi rads)';
            app.XAxisRotationpiradsSlider = uislider(app.ManualTab);
            app.XAxisRotationpiradsSlider.Limits = [-1 1];
            app.XAxisRotationpiradsSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.XAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.75', '-0.5', '-0.25', '0', '0.25', '0.5', '0.75', '1'};
            app.XAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @XAxisRotationpiradsSliderValueChanged, true);
            app.XAxisRotationpiradsSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.XAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the x-axis'};
            app.YAxisRotationpiradsSliderLabel = uilabel(app.ManualTab);
            app.YAxisRotationpiradsSliderLabel.Text = 'Y-Axis Rotation (pi rads)';
            app.YAxisRotationpiradsSlider = uislider(app.ManualTab);
            app.YAxisRotationpiradsSlider.Limits = [-1 1];
            app.YAxisRotationpiradsSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.YAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.75', '-0.5', '-0.25', '0', '0.25', '0.5', '0.75', '1'};
            app.YAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @YAxisRotationpiradsSliderValueChanged, true);
            app.YAxisRotationpiradsSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.YAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the y-axis'};
            app.ZAxisRotationpiradsSliderLabel = uilabel(app.ManualTab);
            app.ZAxisRotationpiradsSliderLabel.Text = 'Z-Axis Rotation (pi rads)';
            app.ZAxisRotationpiradsSlider = uislider(app.ManualTab);
            app.ZAxisRotationpiradsSlider.Limits = [-1 1];
            app.ZAxisRotationpiradsSlider.MajorTicks = [-1 -0.75 -0.5 -0.25 0 0.25 0.5 0.75 1];
            app.ZAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.75', '-0.5', '-0.25', '0', '0.25', '0.5', '0.75', '1'};
            app.ZAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @ZAxisRotationpiradsSliderValueChanged, true);
            app.ZAxisRotationpiradsSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.ZAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the coil''s axis'};
            app.ResetRotationButton_2 = uibutton(app.ManualTab, 'push');
            app.ResetRotationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton_2.Tooltip = {'Resets the rotation of the coil'};
            app.ResetRotationButton_2.Text = 'Reset Rotation';
            app.ResetTranslationButton_2 = uibutton(app.ManualTab, 'push');
            app.ResetTranslationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetTranslationButtonPushed, true);
            app.ResetTranslationButton_2.Tooltip = {'Resets the translation of the coil'};
            app.ResetTranslationButton_2.Text = 'Reset Translation';
            app.ExportingTab = uitab(app.WindowTabGroup);
            app.ExportingTab.Title = 'Exporting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end