classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        WindowTabGroup                  matlab.ui.container.TabGroup
        ImportingTab                    matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        LoadPreviousSessionButton       matlab.ui.control.Button
        ImportStatusLamp                matlab.ui.control.Lamp
        UITable                         matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        LoadNiftiDataButton             matlab.ui.control.Button
        LoadCoilButton                  matlab.ui.control.Button
        ProceedafterSelectionButton     matlab.ui.control.Button
        SelectSTLsButton                matlab.ui.control.Button
        PlanningTab                     matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        PlanesTab                       matlab.ui.container.Tab
        CoilDisplayVisibilitySwitch     matlab.ui.control.Switch
        CoilDisplayVisibilitySwitchLabel  matlab.ui.control.Label
        DeletePlaneButton               matlab.ui.control.Button
        AddNewPlaneButton               matlab.ui.control.Button
        PlaneWidthcmEditField           matlab.ui.control.NumericEditField
        PlaneWidthcmEditFieldLabel      matlab.ui.control.Label
        CenterOnPointButton             matlab.ui.control.Button
        PlaneNameEditField              matlab.ui.control.EditField
        PlaneNameEditFieldLabel         matlab.ui.control.Label
        DirectionDropDown               matlab.ui.control.DropDown
        DirectionDropDownLabel          matlab.ui.control.Label
        ZCoordcmSlider                  matlab.ui.control.Slider
        ZCoordcmSliderLabel             matlab.ui.control.Label
        YCoordcmSlider                  matlab.ui.control.Slider
        YCoordcmSliderLabel             matlab.ui.control.Label
        XCoordcmSlider                  matlab.ui.control.Slider
        XCoordcmSliderLabel             matlab.ui.control.Label
        PlanesDropDown                  matlab.ui.control.DropDown
        PlanesDropDownLabel             matlab.ui.control.Label
        LinesTab                        matlab.ui.container.Tab
        CoilDisplayLineVisibilitySwitch  matlab.ui.control.Switch
        CoilDisplayVisibilitySwitch_2Label  matlab.ui.control.Label
        ZVectorDirectionEditField       matlab.ui.control.NumericEditField
        ZVectorDirectionEditFieldLabel  matlab.ui.control.Label
        YVectorDirectionEditField       matlab.ui.control.NumericEditField
        YVectorDirectionEditFieldLabel  matlab.ui.control.Label
        XVectorDirectionEditField       matlab.ui.control.NumericEditField
        XVectorDirectionEditFieldLabel  matlab.ui.control.Label
        LineLengthcmEditField           matlab.ui.control.NumericEditField
        LineLengthcmEditFieldLabel      matlab.ui.control.Label
        CenterLineOnPointButton         matlab.ui.control.Button
        DeleteLineButton                matlab.ui.control.Button
        AddNewLineButton                matlab.ui.control.Button
        LineNameEditField               matlab.ui.control.EditField
        LineNameEditFieldLabel          matlab.ui.control.Label
        LinesDropDown                   matlab.ui.control.DropDown
        LinesDropDownLabel              matlab.ui.control.Label
        ZCoordLinecmSlider              matlab.ui.control.Slider
        ZCoordcmSlider_2Label           matlab.ui.control.Label
        YCoordLinecmSlider              matlab.ui.control.Slider
        YCoordcmSlider_2Label           matlab.ui.control.Label
        XCoordLinecmSlider              matlab.ui.control.Slider
        XCoordcmSlider_2Label           matlab.ui.control.Label
        CoilControlsTabGroup            matlab.ui.container.TabGroup
        AutomaticTab                    matlab.ui.container.Tab
        AddObservationLineButton        matlab.ui.control.Button
        AddObservationPlanesButton      matlab.ui.control.Button
        ResetTranslationButton          matlab.ui.control.Button
        mmFromSkinSlider                matlab.ui.control.Slider
        mmFromSkinSliderLabel           matlab.ui.control.Label
        CoilAxisRotationpiradsSlider    matlab.ui.control.Slider
        CoilAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        ResetRotationButton             matlab.ui.control.Button
        UpdateDisplayButton             matlab.ui.control.Button
        PointZValEditField              matlab.ui.control.NumericEditField
        PointYValEditField              matlab.ui.control.NumericEditField
        PointXValEditField              matlab.ui.control.NumericEditField
        UpdateTargetButton              matlab.ui.control.Button
        ChosenTargetcmLabel             matlab.ui.control.Label
        ManualTab                       matlab.ui.container.Tab
        ResetTranslationButton_2        matlab.ui.control.Button
        ResetRotationButton_2           matlab.ui.control.Button
        ZAxisRotationpiradsSlider       matlab.ui.control.Slider
        ZAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        YAxisRotationpiradsSlider       matlab.ui.control.Slider
        YAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        XAxisRotationpiradsSlider       matlab.ui.control.Slider
        XAxisRotationpiradsSliderLabel  matlab.ui.control.Label
        ZTranslationcmSlider            matlab.ui.control.Slider
        ZTranslationcmSliderLabel       matlab.ui.control.Label
        YTranslationcmSlider            matlab.ui.control.Slider
        YTranslationcmSliderLabel       matlab.ui.control.Label
        XTranslationcmSlider            matlab.ui.control.Slider
        XTranslationcmSliderLabel       matlab.ui.control.Label
        TransformationMatrixSwitch      matlab.ui.control.Switch
        TransformationMatrixVisabilityLabel  matlab.ui.control.Label
        VisualControlsPanel             matlab.ui.container.Panel
        NiftiDataDropDown               matlab.ui.control.DropDown
        NiftiDataDropDownLabel          matlab.ui.control.Label
        ScalecmSlider                   matlab.ui.control.Slider
        ScalecmSliderLabel              matlab.ui.control.Label
        HeadCompartmentsDropDown        matlab.ui.control.DropDown
        HeadCompartmentsDropDownLabel   matlab.ui.control.Label
        VectorfromcoilSwitch            matlab.ui.control.Switch
        VectorfromcoilSwitchLabel       matlab.ui.control.Label
        FieldVectorSwitch               matlab.ui.control.Switch
        FieldVectorSwitchLabel          matlab.ui.control.Label
        ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel  matlab.ui.container.Panel
        Versionv031alphaLabel           matlab.ui.control.Label
        CoilTransformationMatrixPanel   matlab.ui.container.Panel
        MatrixField44                   matlab.ui.control.NumericEditField
        MatrixField33                   matlab.ui.control.NumericEditField
        MatrixField34                   matlab.ui.control.NumericEditField
        MatrixField24                   matlab.ui.control.NumericEditField
        MatrixField43                   matlab.ui.control.NumericEditField
        MatrixField23                   matlab.ui.control.NumericEditField
        MatrixField14                   matlab.ui.control.NumericEditField
        MatrixField12                   matlab.ui.control.NumericEditField
        MatrixField22                   matlab.ui.control.NumericEditField
        MatrixField32                   matlab.ui.control.NumericEditField
        MatrixField13                   matlab.ui.control.NumericEditField
        MatrixField11                   matlab.ui.control.NumericEditField
        MatrixField42                   matlab.ui.control.NumericEditField
        MatrixField41                   matlab.ui.control.NumericEditField
        MatrixField31                   matlab.ui.control.NumericEditField
        MatrixField21                   matlab.ui.control.NumericEditField
        CrossSectionDisplay             matlab.ui.control.UIAxes
        CoilDisplay                     matlab.ui.control.UIAxes
        ProcessingTab                   matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        LampStartProcessing             matlab.ui.control.Lamp
        MinimumResidualEditField        matlab.ui.control.NumericEditField
        MinimumResidualEditFieldLabel   matlab.ui.control.Label
        GeneralOptionsLabel             matlab.ui.control.Label
        SolverStopCriteriaLabel         matlab.ui.control.Label
        MaximumIterationsEditField      matlab.ui.control.NumericEditField
        MaximumIterationsEditFieldLabel  matlab.ui.control.Label
        CoilCurrentEditField            matlab.ui.control.NumericEditField
        CoilCurrentEditFieldLabel       matlab.ui.control.Label
        StartProcessingButton           matlab.ui.control.Button
        NumberOfThreadsForProcessingEditField  matlab.ui.control.NumericEditField
        NumberofcoresforprocessingLabel  matlab.ui.control.Label
        ExportingTab                    matlab.ui.container.Tab
    end


    properties (Access = public)
        skinPoint % The chosen point on the skin
        directionNVector % Normal direction vector for coil placement
        userpointobj % the display object for the user's point

        %Import table
        meshList % Cell array that stores read stl files from the import table
        meshInternalNames % Cell array that stores internal names from the import table
        meshConductivities % Cell array that stores conductivities from the import table
        meshPath % Cell array that stores pathes from the import table
        meshEncTissues % Cell array that stores enclosing tissues from the import table
        meshFileNames % Cell array that stores file names from the import table

        mesh    % CombinedMesh
        coil % coil's mesh
        nifti_filepath % nifti file names
        niftidata % data from the nifti file
        coilpatch % the patch display of the coil
        coilnormalline % the line displaying the coil's direction
        coilfieldline % the line showing the direction of the coil's field
        brainpatch % the patch display of the brain
        coillight % the light on the coil
        brainlight % the light on the brain
        planes % a 1xn cell array of different planes. Each idx contains another cell array consisting of
        %   {name, direction('xy', 'xz', or 'yz'), [xCoord(cm), yCoord(cm), zCoord(cm)], visability(true/false), patch object}
        selectedplaneidx % the index of the currently selected plane in the planes cell array

        lines % a 1xn cell array of different observation lines. Each idx contains another cell array consisting of
        %   {name, [NormalXComponent, NormalYComponent, NormalZComponent,
        %   Length (cm)], [xCoord(cm), yCoord(cm), zCoord(cm)], visability(true/false), plot3 object}
        selectedlineidx  % the index of the currently selected line in the lines cell array

        niftidisplaydata % contains the display objects for all of the added objects on the nifti display plane
        % Properties: userpoint, coilcenterline, centerlineintersection,
        % fieldplane

        dcm_obj % Handle for the UIFigure
    end

    properties (Access = private)
        
    end

    methods (Access = public)

        function updateImage(app)
            %%  The main function called to update the UIAxes (with the coil and brain)

            %   rounds the fields in the matrix
            roundmatrixfield(app);

            %   displays the brain
            displaybrain(app);

            %   displays the coil
            displaycoil(app);

            %   displays the plane cross-sections
            displayplanes(app);
        end
    end

    methods (Access = private)

        function addpathes(app)
            %   Ensure that this step works for unix and windows
            s = fileparts(pwd);
            if(~isunix)
                slash = '\';
            else
                slash = '/';
            end

            %   Setting the pathes
            Coil_Path = [s, slash, 'Coil'];
            Engine_Path = [s, slash, 'Engine'];
            Engine_2_Path = [s, slash, 'Engine_2'];
            Engine_3_Path = [s, slash, 'Engine_3'];
            Engine_Vis_Path = [s, slash, 'Engine_Vis'];
            GUIEngine_Path = [s, slash, 'GUIEngine'];
            Model_Path = [s, slash, 'Model'];
            Model_2_Path = [s, slash, 'Model_2'];

            %   Generating the pathes
            addpath(genpath(Coil_Path));
            addpath(genpath(Engine_Path));
            addpath(genpath(Engine_2_Path));
            addpath(genpath(Engine_3_Path));
            addpath(genpath(Engine_Vis_Path));
            addpath(genpath(GUIEngine_Path));
            addpath(genpath(Model_Path));
            addpath(genpath(Model_2_Path));
        end

        function roundmatrixfield(app)

            %   Rounds all of the fields in the matrix
            app.MatrixField11.Value = round(app.MatrixField11.Value, 10);
            app.MatrixField12.Value = round(app.MatrixField12.Value, 10);
            app.MatrixField13.Value = round(app.MatrixField13.Value, 10);
            app.MatrixField21.Value = round(app.MatrixField21.Value, 10);
            app.MatrixField22.Value = round(app.MatrixField22.Value, 10);
            app.MatrixField23.Value = round(app.MatrixField23.Value, 10);
            app.MatrixField31.Value = round(app.MatrixField31.Value, 10);
            app.MatrixField32.Value = round(app.MatrixField32.Value, 10);
            app.MatrixField33.Value = round(app.MatrixField33.Value, 10);
            app.MatrixField41.Value = round(app.MatrixField41.Value, 10);
            app.MatrixField42.Value = round(app.MatrixField42.Value, 10);
            app.MatrixField43.Value = round(app.MatrixField43.Value, 10);

        end
        
        function postImportSetup(app)
            
            edgeDistance = 0.2; %  Prevents errors from indexing out of bounds
            app.XCoordcmSlider.Limits = [min(app.mesh.P(:, 1))*1e2+edgeDistance, max(app.mesh.P(:, 1))*1e2-edgeDistance];
            app.YCoordcmSlider.Limits = [min(app.mesh.P(:, 2))*1e2+edgeDistance, max(app.mesh.P(:, 2))*1e2-edgeDistance];
            app.ZCoordcmSlider.Limits = [min(app.mesh.P(:, 3))*1e2+edgeDistance, max(app.mesh.P(:, 3))*1e2-edgeDistance];

            app.HeadCompartmentsDropDown.Items = app.meshInternalNames;
            app.NiftiDataDropDown.Items = app.nifti_filepath;

            app.planes = cell(0);

            %   Resets matrix
            event = 0; % Basically null equivilent
            ResetRotationButtonPushed(app, event);
            ResetTranslationButtonPushed(app, event);

        end
        
        function [outerLayer, idx] = findOuterLayer(app)
            %%  Function that finds the outer layer / index of the outer layer of the stl meshes imported
            %   The method used is to find the layer that is enclosed by
            %   none of the other stl layers (ie enclosed by free space)

            for (i = 1:size(app.meshEncTissues,2))

                encTissue = app.meshEncTissues{i};

                if ~any(strcmp(app.meshInternalNames, encTissue))
                    idx = i;
                    outerLayer = app.meshInternalNames{i};
                end
            end
        end
        
        function changeselectedplane(app)
            %   Updates the plane's controls to reflect the settings
            %   defined for the plane at app.planes{app.selectedplaneidx}
            idx = app.selectedplaneidx;
            
            app.PlanesDropDown.Value = idx;
            app.PlaneNameEditField.Value = app.planes{idx}{1};
            app.DirectionDropDown.Value = app.planes{idx}{2};
            app.XCoordcmSlider.Value = app.planes{idx}{3}(1);
            app.YCoordcmSlider.Value = app.planes{idx}{3}(2);
            app.ZCoordcmSlider.Value = app.planes{idx}{3}(3);
            app.CoilDisplayVisibilitySwitch.Value = app.planes{idx}{4};

            %   Update display to reflect the new tab
            displayplanes(app);

        end
        
        function updateplanedropdown(app)

            %   assign the planes drop down menu the names of the currently
            %   created planes and assign the values to correspond with the
            %   index of the plane in app.planes
            app.PlanesDropDown.Items = {}; % clear to make sure that there are not too many items
            for (i = 1:length(app.planes))
                app.PlanesDropDown.Items{i} = app.planes{i}{1}; % assign the names of the items
                app.PlanesDropDown.ItemsData{i} = i; % set the data to be the index of the selected 
                % item in the planes drop down menu (same as the index of the plane in app.planes)
            end
            
        end
        
        function updatelinedropdown(app)

            %   assign the lines drop down menu the names of the currently
            %   created lines and assign the values to correspond with the
            %   index of the line in app.lines
            app.LinesDropDown.Items = {}; % clear to make sure that there are not too many items
            for (i = 1:length(app.lines))
                app.LinesDropDown.Items{i} = app.lines{i}{1}; % assign the names of the items
                app.LinesDropDown.ItemsData{i} = i; % set the data to be the index of the selected 
                % item in the planes drop down menu (same as the index of the plane in app.planes)
            end
            
        end
        
        function changeselectedline(app)
            %   Updates the plane's controls to reflect the settings
            %   defined for the plane at app.planes{app.selectedplaneidx}
            idx = app.selectedlineidx;
            
            app.LinesDropDown.Value = idx;
            app.LineNameEditField.Value = app.lines{idx}{1};
            app.XVectorDirectionEditField.Value = app.lines{idx}{2}(1);
            app.YVectorDirectionEditField.Value = app.lines{idx}{2}(2);
            app.ZVectorDirectionEditField.Value = app.lines{idx}{2}(3);
            app.LineLengthcmEditField.Value = app.lines{idx}{2}(4);
            app.XCoordLinecmSlider.Value = app.lines{idx}{3}(1);
            app.YCoordLinecmSlider.Value = app.lines{idx}{3}(2);
            app.ZCoordLinecmSlider.Value = app.lines{idx}{3}(3);
            app.CoilDisplayLineVisibilitySwitch.Value = app.lines{idx}{4};

            %   Update display to reflect the new tab
            displayobslines(app);

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %   Adds the pathes for the required scripts
            addpathes(app);

            %   Turns hold on for both displays
            hold(app.CrossSectionDisplay, "on");
            hold(app.CoilDisplay, "on");

            %   Scale Matrix (m to cm)
            scaleMatrix = [100 0 0;
                0 100 0;
                0 0 100];

            %sets a few properties of the CoilDisplay axes
            hold(app.CoilDisplay, "on");
            grid(app.CoilDisplay,'on');
            app.CoilDisplay.XLim = [-20, 20];
            app.CoilDisplay.YLim = [-20, 20];
            app.CoilDisplay.ZLim = [-20, 20];


            %   Labels the units
            xlabel(app.CoilDisplay, 'x, cm');
            ylabel(app.CoilDisplay, 'y, cm');
            zlabel(app.CoilDisplay, 'z, cm');

            %   Sets the update function for clicking points on the
            %   CoilDisplay axes
            datacursormode(app.UIFigure, 'on');
            app.dcm_obj = datacursormode(app.UIFigure);
            set(app.dcm_obj,'UpdateFcn',@pointClickUpdateFunction);

        end

        % Value changed function: MatrixField11, MatrixField12, 
        % ...and 14 other components
        function MatrixFieldValueChanged(app, event)

            %   Update the image
            displaycoil(app);


        end

        % Value changed function: XTranslationcmSlider
        function XTranslationcmSliderValueChanged(app, event)
            %   Adds the new x-translation to the already existing
            %   x-translation
            value = app.XTranslationcmSlider.Value;
            app.MatrixField14.Value = app.MatrixField14.Value + value;

            %   Reset the translation slider to be centered at 0
            app.XTranslationcmSlider.Value = 0;

            %   updates the image
            displaycoil(app);


        end

        % Value changed function: YTranslationcmSlider
        function YTranslationcmSliderValueChanged(app, event)
            value = app.YTranslationcmSlider.Value;
            app.MatrixField24.Value = app.MatrixField24.Value + value;

            %   Reset the translation slider to be centered at 0
            app.YTranslationcmSlider.Value = 0;

            %   updates the image
            displaycoil(app);


        end

        % Value changed function: ZTranslationcmSlider
        function ZTranslationcmSliderValueChanged(app, event)
            value = app.ZTranslationcmSlider.Value;
            app.MatrixField34.Value = app.MatrixField34.Value + value;

            %   Reset the translation slider to be centered at 0
            app.ZTranslationcmSlider.Value = 0;

            %   updates the image
            displaycoil(app);


        end

        % Value changed function: XAxisRotationpiradsSlider
        function XAxisRotationpiradsSliderValueChanged(app, event)
            value = app.XAxisRotationpiradsSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.XAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the x-axis
            Rx = [1, 0, 0;
                0, cos(value), sin(value);
                0, -sin(value), cos(value)];    %X's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the x-axis to the current
            %   rotation matrix
            newRot = Rx*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            displaycoil(app);


        end

        % Value changed function: YAxisRotationpiradsSlider
        function YAxisRotationpiradsSliderValueChanged(app, event)
            value = app.YAxisRotationpiradsSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.YAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the y-axis
            Ry = [cos(value), 0, -sin(value);
                0, 1, 0;
                sin(value), 0, cos(value)];  %Y's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = Ry*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            displaycoil(app);

            % Update the cross section display
            updatecoilnormaltocrosssectiondisplay(app);

        end

        % Value changed function: CoilAxisRotationpiradsSlider
        function CoilAxisRotationpiradsSliderValueChanged(app, event)
            value = app.CoilAxisRotationpiradsSlider.Value * pi;

            %   Reset the rotation slider to be centered at 0
            app.CoilAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the z-axis
            Rz = [cos(value), -sin(value), 0;
                sin(value), cos(value), 0;
                0, 0, 1];    %Z's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = CurrentRot*Rz;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            displaycoil(app);

        end

        % Button pushed function: ResetRotationButton, 
        % ...and 1 other component
        function ResetRotationButtonPushed(app, event)

            %   Restore rotation matrix to identity matrix
            app.MatrixField11.Value = 1;
            app.MatrixField12.Value = 0;
            app.MatrixField13.Value = 0;
            app.MatrixField21.Value = 0;
            app.MatrixField22.Value = 1;
            app.MatrixField23.Value = 0;
            app.MatrixField31.Value = 0;
            app.MatrixField32.Value = 0;
            app.MatrixField33.Value = 1;

            %   Update visuals
            displaycoil(app);


        end

        % Value changed function: ScalecmSlider
        function ScalecmSliderValueChanged(app, event)
            value = app.ScalecmSlider.Value;

            app.CoilDisplay.XLim = [-value,value];
            app.CoilDisplay.YLim = [-value,value];
            app.CoilDisplay.ZLim = [-value,value];

        end

        % Button pushed function: ResetTranslationButton, 
        % ...and 1 other component
        function ResetTranslationButtonPushed(app, event)

            %   Restore translation matrix
            app.MatrixField14.Value = 0;
            app.MatrixField24.Value = 0;
            app.MatrixField34.Value = 15;

            %   Update visuals
            displaycoil(app);

        end

        % Callback function
        function HeadCompartmentsButtonGroupSelectionChanged(app, event)
            %selectedButton = app.HeadCompartmentsButtonGroup.SelectedObject;

            %   Update visuals
            displaybrain(app);

        end

        % Value changed function: FieldVectorSwitch, VectorfromcoilSwitch
        function VectortocoilSwitchValueChanged(app, event)
            %   Update visuals
            displaycoil(app);
        end

        % Button pushed function: UpdateDisplayButton
        function UpdateDisplayButtonPushed(app, event)

            disp('Display Updated')

            point = [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value] .* 1e-2;


            %   Find centers, normal vectors, and t on the skin
            Center = app.mesh.Center;
            normals = app.mesh.normals;
            Indicator = app.mesh.Indicator;
            P = app.mesh.P;
            t = app.mesh.t;


            %%  How to find the outside layer (Skin) rather than the first item
            [~, idx] = findOuterLayer(app);
            

            eligibleCenters = Center(Indicator==idx,:);
            eligibleNormals = normals(Indicator==idx,:);
            eligibleT = t(Indicator==idx,:);

            %   Find the index of the closest point (center of triangle) on the skin
            Idx1 = knnsearch(eligibleCenters, point);

            %   Find the normals of chosen triangle and its neighbors and
            %   average them together
            DT = triangulation(eligibleT,P);
            tNeighbor = neighbors(DT);
            trianglesOfInterest = [Idx1 tNeighbor(Idx1,:)];
            normalsOfInterest = eligibleNormals(trianglesOfInterest,:);
            selectedNormal = mean(normalsOfInterest)/norm(mean(normalsOfInterest));
            app.directionNVector = selectedNormal;

            %   Find the distance from the user's point to it's
            %   intersection with the skin
            intersectionDistance = meshsegtrintersection(point, selectedNormal, 1, P, eligibleT);
            if (any(isnan(intersectionDistance)))
                error('Something went terribly wrong');
            end
            intersectionDistance = intersectionDistance(intersectionDistance~=0);

            %   If there is no intersection with the skin from the user
            %   selected point in the direction of the skin's normal then
            %   you are already on the skin (if on the model) so use the
            %   user's point as the skinpoint
            if (isempty(intersectionDistance))
                
                app.skinPoint = point;

            else

                %   Find the point of intersection with the skin
                app.skinPoint = point + intersectionDistance*selectedNormal;
            end

            %   Translate the coil
            translations = app.skinPoint + app.directionNVector .* app.mmFromSkinSlider.Value * 1e-3;   %mm
            translations = translations .* 1e2;
            app.MatrixField14.Value = translations(1);
            app.MatrixField24.Value = translations(2);
            app.MatrixField34.Value = translations(3);


            %   Rotate the coil to face opposite the selected normal vector
            angle = 0;
            a = [0,0,-1];   % Vector we are starting at
            b = selectedNormal*(-1); % Vector we want
            v = cross(a, b);
            s = norm(cross(a,b)) * sin(angle);
            c = dot(a,b)*cos(angle);
            vCrossMatrix = [0, -v(3), v(2); v(3), 0, -v(1);-v(2), v(1), 0];

            rotationMatrix = eye(3) + vCrossMatrix + vCrossMatrix^2 * 1/(1+c);
            app.MatrixField11.Value = rotationMatrix(1,1);
            app.MatrixField12.Value = rotationMatrix(1,2);
            app.MatrixField13.Value = rotationMatrix(1,3);
            app.MatrixField21.Value = rotationMatrix(2,1);
            app.MatrixField22.Value = rotationMatrix(2,2);
            app.MatrixField23.Value = rotationMatrix(2,3);
            app.MatrixField31.Value = rotationMatrix(3,1);
            app.MatrixField32.Value = rotationMatrix(3,2);
            app.MatrixField33.Value = rotationMatrix(3,3);

            % Update coil display
            displaycoil(app);

        end

        % Button pushed function: UpdateTargetButton
        function UpdateTargetButtonPushed(app, event)
            %   Updates point param to edit fields
            info_struct = getCursorInfo(app.dcm_obj);
            if (isfield(info_struct, 'Position') && length(info_struct.Position) == 3)
                point = info_struct.Position;

                app.PointXValEditField.Value = round(point(1), 3);
                app.PointYValEditField.Value = round(point(2), 3);
                app.PointZValEditField.Value = round(point(3), 3);

                % Update user's point on the coildisplay
                delete(app.userpointobj);
                app.userpointobj = plot3(app.CoilDisplay, app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value, Color = 'green', Marker= '*', MarkerSize=10);
                
                % Update user's point on the crosssectiondisplay
                updateuserpointcrosssectiondisplay(app);

            elseif(isfield(info_struct, 'Position') && length(info_struct.Position) == 2)
                point = info_struct.Position .* 0.1; %Convert from mm to cm

                switch app.planes{app.selectedplaneidx}{2}
                    case 'xy'
                        app.PointXValEditField.Value = round(point(1), 3);
                        app.PointYValEditField.Value = round(point(2), 3);
                        app.PointZValEditField.Value = round(app.planes{app.selectedplaneidx}{3}(3), 3);
                    case 'xz'
                        app.PointXValEditField.Value = round(point(1), 3);
                        app.PointYValEditField.Value = round(app.planes{app.selectedplaneidx}{3}(2), 3);
                        app.PointZValEditField.Value = round(point(2), 3);
                    case 'yz'
                        app.PointXValEditField.Value = round(app.planes{app.selectedplaneidx}{3}(1), 3);
                        app.PointYValEditField.Value = round(point(1), 3);
                        app.PointZValEditField.Value = round(point(2), 3);

                end

                % Update user's point on the coildisplay
                delete(app.userpointobj);
                app.userpointobj = plot3(app.CoilDisplay, app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value, Color = 'green', Marker= '*', MarkerSize=10);

                % Update user's point on the crosssectiondisplay
                updateuserpointcrosssectiondisplay(app);
            end
        end

        % Value changed function: TransformationMatrixSwitch
        function TransformationMatrixSwitchValueChanged(app, event)
            value = app.TransformationMatrixSwitch.Value;
            switch value
                case 'Off'

                    %   Disables visibility of the Transformation Matrix
                    app.CoilTransformationMatrixPanel.Visible = "off";

                case 'On'

                    %   Enables visibility of the Transformation Matrix
                    app.CoilTransformationMatrixPanel.Visible = "on";
            end

        end

        % Value changed function: ZAxisRotationpiradsSlider
        function ZAxisRotationpiradsSliderValueChanged(app, event)
            value = app.ZAxisRotationpiradsSlider.Value*pi;

            %   Reset the rotation slider to be centered at 0
            app.ZAxisRotationpiradsSlider.Value = 0;

            %   getting the rotational matrix for the specified rotation
            %   around the z-axis
            Rz = [cos(value), -sin(value), 0;
                sin(value), cos(value), 0;
                0, 0, 1];    %Z's rotation matrix

            %   turning the edit fields for the rotation in the matrix into a matrix
            CurrentRot = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];  %current rotation matrix


            %   applying the rotation around the y-axis to the current
            %   rotation matrix
            newRot = Rz*CurrentRot;

            %   Updating all of the fields in the matrix
            app.MatrixField11.Value = newRot(1, 1);
            app.MatrixField12.Value = newRot(1, 2);
            app.MatrixField13.Value = newRot(1, 3);
            app.MatrixField21.Value = newRot(2, 1);
            app.MatrixField22.Value = newRot(2, 2);
            app.MatrixField23.Value = newRot(2, 3);
            app.MatrixField31.Value = newRot(3, 1);
            app.MatrixField32.Value = newRot(3, 2);
            app.MatrixField33.Value = newRot(3, 3);

            %   Update the display
            displaycoil(app);

        end

        % Value changed function: HeadCompartmentsDropDown
        function HeadCompartmentsDropDownValueChanged(app, event)

            %   Updates the display. The checking is handled in
            %   AppFunctions/displaybrain.m
            displaybrain(app);
        end

        % Callback function
        function CrossSectionControlsTabGroupSelectionChanged(app, event)

            %   Update the image
            displayplanes(app);
        end

        % Button pushed function: SelectSTLsButton
        function SelectSTLsButtonPushed(app, event)

            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

            %   Save file and path of the opened documents
            [file,path] = uigetfile('*.stl', 'Select one or more stl files', 'MultiSelect', 'on');

            delete(f); %delete the dummy figure

            n_files = numel(cellstr(file));

            %   Initialize cell arrays
            app.meshConductivities = cell(1,n_files);
            app.meshPath = cell(1,n_files);
            app.meshInternalNames = cell(1,n_files);
            app.meshList = cell(1,n_files);
            app.meshEncTissues = cell(1,n_files);
            app.meshFileNames = cell(1,n_files);

            %   Change values in the table
            for k = 1:n_files
                if n_files==1
                    fileOut = file;
                else
                    fileOut = file{k};
                end
            
            %  Parse common tissue name from filename
            %  Expression is the list of tissue names that the function will
            %  search for in the filename. 
            %  More names can be added following the same format
            expression = 'bone|skull|csf|eyes|gm|skin|ventricles|wm|cerebellum';

            %  regexpi: Case-insensitive regex search for a tissue name in fileOut.
            %  lower: forces lowercase output for consistency
            %  regFileOut: Cell array with the tissue name from fileOut
            regFileOut = lower(regexpi(fileOut,expression,'match'));

            %  regFileChar: Char array for use in switch statement
            regFileChar = char(regFileOut);

            %  Switch statement takes regFileChar and assigns default
            %  name, conductivity, and enclosing tissue
            %  This list of cases can be expanded to accomodate more
            %  tissue names
            switch regFileChar
                case 'bone', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %  \/
                case 'skull', nameFile{k} = 'Skull'; conductivity{k} = 0.010; enclosingTissue{k} = 'Skin';  %   /\ Treating bone and skull the same
                case 'csf', nameFile{k} = 'CSF'; conductivity{k} = 1.654 ; enclosingTissue{k} = 'Skull';
                case 'eyes', nameFile{k} = 'Eyes'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';  %  Default conductivity might not be right!!
                case 'gm', nameFile{k} = 'GM'; conductivity{k} = 0.2750; enclosingTissue{k} = 'CSF';
                case 'skin', nameFile{k} = 'Skin'; conductivity{k} = 0.4650; enclosingTissue{k} = 'FreeSpace';
                case 'ventricles', nameFile{k} = 'Ventricles'; conductivity{k} = 1.654; enclosingTissue{k} = 'WM';
                case 'wm', nameFile{k} = 'WM'; conductivity{k} = 0.1260; enclosingTissue{k} = 'GM';
                case 'cerebellum', nameFile{k} = 'Cerebellum'; conductivity{k} = 0.1260; enclosingTissue{k} = 'CSF';
                otherwise, nameFile{k} = '[EnterName]'; conductivity{k} = '[EnterConductivity]'; enclosingTissue{k} = '[EnterEnclosingTissue]';
            end
            
                if k == 1
                    app.UITable.Data = {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}};
                else
                    app.UITable.Data = [app.UITable.Data; {int2str(k) path fileOut nameFile{k} conductivity{k} enclosingTissue{k}}];
                end
            end

        end

        % Button pushed function: ProceedafterSelectionButton
        function ProceedafterSelectionButtonPushed(app, event)
            % Turn status lamp color to orange
            app.ImportStatusLamp.Color = [1,1,0];
            pause(0.5);

            %   Save data from the table in cell arrays
            for i = 1:size(app.UITable.Data,1)

                app.meshPath{i} = cell2mat(app.UITable.Data(i,2));
                app.meshFileNames{i} = cell2mat(app.UITable.Data(i,3));
                app.meshList{i} = stlread(cell2mat(app.UITable.Data(i,3)));
                app.meshInternalNames{i} = cell2mat(app.UITable.Data(i,4));
                app.meshConductivities{i} = cell2mat(app.UITable.Data(i,5));
                app.meshEncTissues{i} = cell2mat(app.UITable.Data(i,6));

            end


            %   Sets up resources for the brain model
            createTxt(app);
            modelProcessor(app);

            app.mesh = load('CombinedMesh.mat');

            app.WindowTabGroup.SelectedTab = app.WindowTabGroup.Children(2);    % Sets the current tab to the second tab in the tab group (Planning tab)

            %   Sets values to conform to the imported data
            postImportSetup(app);

            %   Displays the initial image
            updateImage(app);
            
            % Change status lamp color to green
            app.ImportStatusLamp.Color = [0,1,0];
        end

        % Value changed function: DirectionDropDown, 
        % ...and 4 other components
        function XCoordcmSliderValueChanged(app, event)

            if (~isempty(app.planes))
                %   Delete the prior patch display for the plane if it
                %   exists
                if (length(app.planes{app.selectedplaneidx}) == 5)
                    delete(app.planes{app.selectedplaneidx}{5});
                end

                %   Make parameters congruent
                app.planes{app.selectedplaneidx}{2} = app.DirectionDropDown.Value;
                app.planes{app.selectedplaneidx}{3} = [app.XCoordcmSlider.Value, app.YCoordcmSlider.Value, app.ZCoordcmSlider.Value];

                %   Update the cross-section
                displayplanes(app);
            end
        end

        % Value changed function: PlanesDropDown
        function PlanesDropDownValueChanged(app, event)
            app.selectedplaneidx = app.PlanesDropDown.Value;

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedplane(app);
        end

        % Button pushed function: AddNewPlaneButton
        function AddNewPlaneButtonPushed(app, event)
            %   adds a new plane with default values and switches to it

            app.selectedplaneidx = length(app.planes) + 1; % select the new plane
            app.planes{app.selectedplaneidx} = {['Plane ' num2str(app.selectedplaneidx)], 'xy', [0, 0, 0], true}; % create the new plane with default values

            updateplanedropdown(app);

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedplane(app);

        end

        % Button pushed function: DeletePlaneButton
        function DeletePlaneButtonPushed(app, event)
            if (~isempty(app.planes))
                if (length(app.planes{app.selectedplaneidx}) == 5)
                    delete(app.planes{app.selectedplaneidx}{5}); % if there is data for the patch
                    % of the plane being deleted then remove the patch from the display
                end
                app.planes(app.selectedplaneidx) = []; % deletes the plane's data in the cell array
                app.selectedplaneidx = 1;

                updateplanedropdown(app);

                if (~isempty(app.planes))
                    %   Display the values of the selected plane to the controls
                    %   (also displays the new plane)
                    changeselectedplane(app);
                else
                    app.PlaneNameEditField.Value = '';
                    cla(app.CrossSectionDisplay);
                end
            end
        end

        % Value changed function: PlaneNameEditField
        function PlaneNameEditFieldValueChanged(app, event)
            value = app.PlaneNameEditField.Value;

            if (~isempty(app.planes))
            app.planes{app.selectedplaneidx}{1} = value;
            updateplanedropdown(app);

            app.PlanesDropDown.Value = app.selectedplaneidx;
            end
            
        end

        % Button pushed function: CenterOnPointButton
        function CenterOnPointButtonPushed(app, event)
            %   Updates point param to edit fields
            info_struct = getCursorInfo(app.dcm_obj);
            if (isfield(info_struct, 'Position') && length(info_struct.Position) == 3)
                point = info_struct.Position;

                app.XCoordcmSlider.Value = round(point(1), 3);
                app.YCoordcmSlider.Value = round(point(2), 3);
                app.ZCoordcmSlider.Value = round(point(3), 3);

                XCoordcmSliderValueChanged(app, event); % event doesn't matter

            elseif(isfield(info_struct, 'Position') && length(info_struct.Position) == 2)
                point = info_struct.Position .* 0.1; %Convert from mm to cm

                switch app.planes{app.selectedplaneidx}{2}
                    case 'xy'
                        app.XCoordcmSlider.Value = round(point(1), 3);
                        app.YCoordcmSlider.Value = round(point(2), 3);
                        app.ZCoordcmSlider.Value = round(app.planes{app.selectedplaneidx}{3}(3), 3);
                    case 'xz'
                        app.XCoordcmSlider.Value = round(point(1), 3);
                        app.YCoordcmSlider.Value = round(app.planes{app.selectedplaneidx}{3}(2), 3);
                        app.ZCoordcmSlider.Value = round(point(2), 3);
                    case 'yz'
                        app.XCoordcmSlider.Value = round(app.planes{app.selectedplaneidx}{3}(1), 3);
                        app.YCoordcmSlider.Value = round(point(1), 3);
                        app.ZCoordcmSlider.Value = round(point(2), 3);

                end
                XCoordcmSliderValueChanged(app, event); % event doesn't matter
            end
        end

        % Button pushed function: LoadNiftiDataButton
        function LoadNiftiDataButtonPushed(app, event)
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

            %   Load nifti data
            [file,path] = uigetfile('*.nii', 'Select one or more nifti files', 'MultiSelect', 'on');

            delete(f);

            n_files = numel(cellstr(file));
            app.nifti_filepath = cell(1,n_files);

            %   Change values in the table (for now works only with one nifti file/display nifti function needs to be changed)

            for k = 1:n_files
                if n_files==1
                    fileOut = file;
                else
                    fileOut = file{k};
                end

                %   Update table with file name
                if k == 1
                    app.UITable2.Data = {fileOut};
                else
                    app.UITable2.Data = [app.UITable2.Data; {fileOut}];
                end

%                  %   Read Nifti Data
                  app.nifti_filepath{k} = cell2mat(app.UITable2.Data(k,1));
%  
%                  if exist(app.nifti_filepath{k}, 'file')
%                      app.niftidata.VT1         = niftiread(app.nifti_filepath{k});
%                      app.niftidata.info        = niftiinfo(app.nifti_filepath{k});
%                  end

            end

            app.niftidata.VT1         = niftiread(app.nifti_filepath{1});
            app.niftidata.info        = niftiinfo(app.nifti_filepath{1});

        end

        % Button pushed function: LoadCoilButton
        function LoadCoilButtonPushed(app, event)
            
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI

            %   Load coil from existing ones
            [file,path] = uigetfile('*.m', 'Select one .m file from Coil Folder');

            delete(f);

            %   Update table with file name
            app.UITable3.Data = {file};

            run(file);

            app.coil = load('coilCAD.mat');

        end

        % Value changed function: NiftiDataDropDown
        function NiftiDataDropDownValueChanged(app, event)
            value = app.NiftiDataDropDown.Value;

            %   Read Nifti Data
            %if exist(value, 'file')
                app.niftidata.VT1         = niftiread(value);
                app.niftidata.info        = niftiinfo(value);
            %end
            updateImage(app);

        end

        % Button pushed function: AddNewLineButton
        function AddNewLineButtonPushed(app, event)
            %   adds a new line with default values and switches to it

            app.selectedlineidx = length(app.lines) + 1; % select the new line
            app.lines{app.selectedlineidx} = {['Line ' num2str(app.selectedlineidx)], [0 0 1 10], [0, 0, 0], true}; % create the new line with default values

            updatelinedropdown(app);

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedline(app);
        end

        % Button pushed function: DeleteLineButton
        function DeleteLineButtonPushed(app, event)
            if (~isempty(app.lines))
                if (length(app.lines{app.selectedlineidx}) == 5)
                    delete(app.lines{app.selectedlineidx}{5}); % if there is data for the patch
                    % of the line being deleted then remove the patch from the display
                end
                app.lines(app.selectedlineidx) = []; % deletes the lines's data in the cell array
                app.selectedlineidx = 1;

                updatelinedropdown(app);

                if (~isempty(app.lines))
                    %   Display the values of the selected line to the controls
                    changeselectedline(app);
                else
                    app.LineNameEditField.Value = '';
                end
            end
        end

        % Button pushed function: CenterLineOnPointButton
        function CenterLineOnPointButtonPushed(app, event)
            %   Updates point param to edit fields
            info_struct = getCursorInfo(app.dcm_obj);
            if (isfield(info_struct, 'Position') && length(info_struct.Position) == 3)
                point = info_struct.Position;

                app.XCoordLinecmSlider.Value = round(point(1), 3);
                app.YCoordLinecmSlider.Value = round(point(2), 3);
                app.ZCoordLinecmSlider.Value = round(point(3), 3);

                XCoordLinecmSliderValueChanged(app, event); % event doesn't matter

            elseif(isfield(info_struct, 'Position') && length(info_struct.Position) == 2)
                point = info_struct.Position .* 0.1; %Convert from mm to cm

                switch app.planes{app.selectedplaneidx}{2}
                    case 'xy'
                        app.XCoordLinecmSlider.Value = round(point(1), 3);
                        app.YCoordLinecmSlider.Value = round(point(2), 3);
                        app.ZCoordLinecmSlider.Value = round(app.planes{app.selectedplaneidx}{3}(3), 3);
                    case 'xz'
                        app.XCoordLinecmSlider.Value = round(point(1), 3);
                        app.YCoordLinecmSlider.Value = round(app.planes{app.selectedplaneidx}{3}(2), 3);
                        app.ZCoordLinecmSlider.Value = round(point(2), 3);
                    case 'yz'
                        app.XCoordLinecmSlider.Value = round(app.planes{app.selectedplaneidx}{3}(1), 3);
                        app.YCoordLinecmSlider.Value = round(point(1), 3);
                        app.ZCoordLinecmSlider.Value = round(point(2), 3);

                end
                XCoordLinecmSliderValueChanged(app, event); % event doesn't matter
            end
        end

        % Value changed function: LineNameEditField
        function LineNameEditFieldValueChanged(app, event)
            value = app.LineNameEditField.Value;

            if (~isempty(app.lines))
            app.lines{app.selectedlineidx}{1} = value;
            updatelinedropdown(app);

            app.LinesDropDown.Value = app.selectedlineidx;
            end
            
            
        end

        % Value changed function: LinesDropDown
        function LinesDropDownValueChanged(app, event)
            app.selectedlineidx = app.LinesDropDown.Value;

            %   Display the values of the selected plane to the controls
            %   (also displays the new plane)
            changeselectedline(app);
        end

        % Value changed function: LineLengthcmEditField, 
        % ...and 6 other components
        function XCoordLinecmSliderValueChanged(app, event)
            if (~isempty(app.lines))
                %   Delete the prior patch display for the plane if it
                %   exists
                if (length(app.lines{app.selectedlineidx}) == 5)
                    delete(app.lines{app.selectedlineidx}{5});
                end
                
                vectMag = sqrt(app.XVectorDirectionEditField.Value^2 + app.YVectorDirectionEditField.Value^2 + app.ZVectorDirectionEditField.Value^2);
                %   Make parameters congruent
                app.lines{app.selectedlineidx}{2} = [app.XVectorDirectionEditField.Value/vectMag, app.YVectorDirectionEditField.Value/vectMag, app.ZVectorDirectionEditField.Value/vectMag, app.LineLengthcmEditField.Value];
                app.lines{app.selectedlineidx}{3} = [app.XCoordLinecmSlider.Value, app.YCoordLinecmSlider.Value, app.ZCoordLinecmSlider.Value];

                %   Update the cross-section
                displayobslines(app);
            end
        end

        % Value changed function: CoilDisplayVisibilitySwitch
        function CoilDisplayVisibilitySwitchValueChanged(app, event)
            if (~isempty(app.planes))
                app.planes{app.selectedplaneidx}{4} = app.CoilDisplayVisibilitySwitch.Value; % update the value in the table
                if ((length(app.planes{app.selectedplaneidx}) == 5) && (~app.planes{app.selectedplaneidx}{4})) % if there is a handle for displayed patch on the coildisplay
                    delete(app.planes{app.selectedplaneidx}{5}) % delete said patch from the display
                elseif (app.planes{app.selectedplaneidx}{4})
                    displayplanes(app);
                end
            end
        end

        % Value changed function: CoilDisplayLineVisibilitySwitch
        function CoilDisplayLineVisibilitySwitchValueChanged(app, event)
            if (~isempty(app.lines))
                app.lines{app.selectedlineidx}{4} = app.CoilDisplayLineVisibilitySwitch.Value; % update the value in the table
                if ((length(app.lines{app.selectedlineidx}) == 5) && (~app.lines{app.selectedlineidx}{4})) % if there is a handle for displayed patch on the coildisplay
                    delete(app.lines{app.selectedlineidx}{5}) % delete said patch from the display
                elseif (app.lines{app.selectedlineidx}{4})
                    displayobslines(app);
                end
            end
        end

        % Button pushed function: AddObservationPlanesButton
        function AddObservationPlanesButtonPushed(app, event)
            app.selectedplaneidx = length(app.planes) + 1; % index into the first of the new planes
            point = [app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value];

            %   Creates the different planes centered at the user's point
            %   and loads them into the coildisplay
            app.planes{app.selectedplaneidx} = {'Generated XY Plane', 'xy', point, true};
            displayplanestocoildisplay(app);

            app.selectedplaneidx = length(app.planes) + 1;
            app.planes{app.selectedplaneidx} = {'Generated XZ Plane', 'xz', point, true};
            displayplanestocoildisplay(app);

            app.selectedplaneidx = length(app.planes) + 1;
            app.planes{app.selectedplaneidx} = {'Generated YZ Plane', 'yz', point, true};
            displayplanestocoildisplay(app);

            app.selectedplaneidx = app.selectedplaneidx - 2; % revert the selected plane to the first plane created (xy)

            updateplanedropdown(app);
            changeselectedplane(app);
            
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            % Change status lamp color orange
            app.LampStartProcessing.Color = [1,1,0];
            pause(1);

            % Run script to do the processing
            processEField(app);

            % Change status lamp color back to green
            app.LampStartProcessing.Color = [0,1,0];
        end

        % Button pushed function: AddObservationLineButton
        function AddObservationLineButtonPushed(app, event)
            app.selectedlineidx = length(app.lines)+1;
            rotMat = [app.MatrixField11.Value, app.MatrixField12.Value, app.MatrixField13.Value;
                app.MatrixField21.Value, app.MatrixField22.Value, app.MatrixField23.Value;
                app.MatrixField31.Value, app.MatrixField32.Value, app.MatrixField33.Value];

            directionNVect = [0, 0, -1];
            newCoilDirection = rotMat * directionNVect';
            newCoilDirection = newCoilDirection';

            app.lines{app.selectedlineidx} = {'Generated Coil Centerline', [newCoilDirection(1), newCoilDirection(2), newCoilDirection(3), 10], [app.MatrixField14.Value, app.MatrixField24.Value, app.MatrixField34.Value], true};

            updatelinedropdown(app);
            changeselectedline(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            %   Save Previous Session

            %   Save point Data
            state.skinPoint = app.skinPoint;
            state.directionNVector = app.directionNVector;

            %   Save STLs Data
            state.meshList = app.meshList;
            state.meshInternalNames = app.meshInternalNames;
            state.meshConductivities = app.meshConductivities;
            state.meshPath = app.meshPath;
            state.meshEncTissues = app.meshEncTissues;
            state.meshFileNames = app.meshFileNames;
            state.UITable.Data = app.UITable.Data;

            %   Save Nifti Data
            state.nifti_filepath = app.nifti_filepath;
            state.niftidata = app.niftidata;
            state.UITable2.Data = app.UITable2.Data;

            %   Save Coil Data
            state.coil = app.coil;
            state.UITable3.Data = app.UITable3.Data;

            %   Save Transformation Matrix
            state.MatrixField44.Value = app.MatrixField44.Value;
            state.MatrixField33.Value = app.MatrixField33.Value;
            state.MatrixField34.Value = app.MatrixField34.Value;
            state.MatrixField24.Value = app.MatrixField24.Value;
            state.MatrixField43.Value = app.MatrixField43.Value;
            state.MatrixField23.Value = app.MatrixField23.Value;
            state.MatrixField14.Value = app.MatrixField14.Value;
            state.MatrixField12.Value = app.MatrixField12.Value;
            state.MatrixField22.Value = app.MatrixField22.Value;
            state.MatrixField32.Value = app.MatrixField32.Value;
            state.MatrixField13.Value = app.MatrixField13.Value;
            state.MatrixField11.Value = app.MatrixField11.Value;
            state.MatrixField42.Value = app.MatrixField42.Value;
            state.MatrixField41.Value = app.MatrixField41.Value;
            state.MatrixField31.Value = app.MatrixField31.Value;
            state.MatrixField21.Value = app.MatrixField21.Value;

            %   Other Saves
            state.dcm_obj = app.dcm_obj;
            state.brainlight = app.brainlight;
            state.planes = app.planes;
            state.selectedplaneidx = app.selectedplaneidx;
            state.lines = app.lines;
            state.selectedlineidx = app.selectedlineidx;

            %   Button Saves
            state.FieldVectorSwitch.Value = app.FieldVectorSwitch.Value;
            state.VectorfromcoilSwitch.Value = app.VectorfromcoilSwitch.Value;
            state.HeadCompartmentsDropDown.Value = app.HeadCompartmentsDropDown.Value;
            state.NiftiDataDropDown.Value = app.NiftiDataDropDown.Value;
            state.ScalecmSlider.Value = app.ScalecmSlider.Value;
            state.TransformationMatrixSwitch.Value = app.TransformationMatrixSwitch.Value;
            state.PlanesDropDown.Value = app.PlanesDropDown.Value;

            %   Save everything to .mat file
            save('prev_session.mat', 'state');
            

            %   Delete app
            delete(app)
            
        end

        % Button pushed function: LoadPreviousSessionButton
        function LoadPreviousSessionButtonPushed(app, event)
            
            %   Load Previous Session

            if(exist('prev_session.mat'))
                load('prev_session.mat');

                fNames = fieldnames(state);
 
                for n = 1:length(fNames)
                    if(contains(fNames{n}, 'UITable') == 1)
                        app.(fNames{n}).Data = state.(fNames{n}).Data;
                    elseif(contains(fNames{n}, 'MatrixField') == 1)
                        app.(fNames{n}).Value = state.(fNames{n}).Value;
                    elseif ~(strcmp(fNames{n}, 'FieldVectorSwitch') || ...
                           strcmp(fNames{n}, 'VectorfromcoilSwitch') || ...
                           strcmp(fNames{n}, 'HeadCompartmentsDropDown') || ...
                           strcmp(fNames{n}, 'NiftiDataDropDown') || ...
                           strcmp(fNames{n}, 'ScalecmSlider') || ...
                           strcmp(fNames{n}, 'TransformationMatrixSwitch') || ...
                           strcmp(fNames{n}, 'PlanesDropDown'))
                        app.(fNames{n}) = state.(fNames{n});
                    end
                end

                % Turn status lamp color to orange
                app.ImportStatusLamp.Color = [1.00,0.41,0.16];
                pause(0.5);

                %   Save data from the table in cell arrays
                for i = 1:size(app.UITable.Data,1)

                    app.meshPath{i} = cell2mat(app.UITable.Data(i,2));
                    app.meshFileNames{i} = cell2mat(app.UITable.Data(i,3));
                    app.meshList{i} = stlread(cell2mat(app.UITable.Data(i,3)));
                    app.meshInternalNames{i} = cell2mat(app.UITable.Data(i,4));
                    app.meshConductivities{i} = cell2mat(app.UITable.Data(i,5));
                    app.meshEncTissues{i} = cell2mat(app.UITable.Data(i,6));

                end
                
                %   Sets up resources for the brain model
                createTxt(app);
                modelProcessor(app);

                app.mesh = load('CombinedMesh.mat');

                app.WindowTabGroup.SelectedTab = app.WindowTabGroup.Children(2);    % Sets the current tab to the second tab in the tab group (Planning tab)

                %   Sets values to conform to the imported data
                edgeDistance = 0.2; %  Prevents errors from indexing out of bounds
                app.XCoordcmSlider.Limits = [min(app.mesh.P(:, 1))*1e2+edgeDistance, max(app.mesh.P(:, 1))*1e2-edgeDistance];
                app.YCoordcmSlider.Limits = [min(app.mesh.P(:, 2))*1e2+edgeDistance, max(app.mesh.P(:, 2))*1e2-edgeDistance];
                app.ZCoordcmSlider.Limits = [min(app.mesh.P(:, 3))*1e2+edgeDistance, max(app.mesh.P(:, 3))*1e2-edgeDistance];

                app.HeadCompartmentsDropDown.Items = app.meshInternalNames;
                app.NiftiDataDropDown.Items = app.nifti_filepath;
                updateplanedropdown(app);

                for n = 1:length(fNames)
                    if(strcmp(fNames{n}, 'FieldVectorSwitch') || ...
                       strcmp(fNames{n}, 'VectorfromcoilSwitch') || ...
                       strcmp(fNames{n}, 'HeadCompartmentsDropDown') || ...
                       strcmp(fNames{n}, 'NiftiDataDropDown') || ...
                       strcmp(fNames{n}, 'ScalecmSlider') || ...
                       strcmp(fNames{n}, 'TransformationMatrixSwitch') || ...
                       strcmp(fNames{n}, 'PlanesDropDown') == 1)
                        app.(fNames{n}).Value = state.(fNames{n}).Value;
                    end
                end

                TransformationMatrixSwitchValueChanged(app, event);
                ScalecmSliderValueChanged(app, event);

                %   Displays the initial image
                updateImage(app);
                %changeselectedplane(app);

            
                % Change status lamp color to green
                app.ImportStatusLamp.Color = [0,1,0];

                delete('prev_session.mat');
            end

        end

        % Value changed function: PointXValEditField, PointYValEditField, 
        % ...and 1 other component
        function PointXValEditFieldValueChanged(app, event)

            % Update user's point on the coildisplay
            delete(app.userpointobj);
            app.userpointobj = plot3(app.CoilDisplay, app.PointXValEditField.Value, app.PointYValEditField.Value, app.PointZValEditField.Value, Color = 'green', Marker= '*', MarkerSize=10);

            % Update user's point on the crosssectiondisplay
            updateuserpointcrosssectiondisplay(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.WindowTabGroup = uitabgroup(app.GridLayout);
            app.ImportingTab = uitab(app.WindowTabGroup);
            app.ImportingTab.Title = 'Importing';
            app.GridLayout3 = uigridlayout(app.ImportingTab);
            app.SelectSTLsButton = uibutton(app.GridLayout3, 'push');
            app.SelectSTLsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectSTLsButtonPushed, true);
            app.SelectSTLsButton.Text = 'Select STLs';
            app.ProceedafterSelectionButton = uibutton(app.GridLayout3, 'push');
            app.ProceedafterSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedafterSelectionButtonPushed, true);
            app.ProceedafterSelectionButton.Text = 'Proceed after Selection';
            app.LoadCoilButton = uibutton(app.GridLayout3, 'push');
            app.LoadCoilButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoilButtonPushed, true);
            app.LoadCoilButton.Text = 'Load Coil';
            app.LoadNiftiDataButton = uibutton(app.GridLayout3, 'push');
            app.LoadNiftiDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNiftiDataButtonPushed, true);
            app.LoadNiftiDataButton.Text = 'Load Nifti Data';
            app.UITable2 = uitable(app.GridLayout3);
            app.UITable2.ColumnName = {'File Name'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.GridLayout3);
            app.UITable3.ColumnName = {'File Name'};
            app.UITable3.RowName = {};
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Item #'; 'File Path'; 'File Name'; 'Internal Name'; 'Conductivities (S/m)'; 'Enclosing Tissue'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.ImportStatusLamp = uilamp(app.GridLayout3);
            app.LoadPreviousSessionButton = uibutton(app.GridLayout3, 'push');
            app.LoadPreviousSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousSessionButtonPushed, true);
            app.LoadPreviousSessionButton.Text = 'Load Previous Session';
            app.PlanningTab = uitab(app.WindowTabGroup);
            app.PlanningTab.Title = 'Planning';
            app.GridLayout2 = uigridlayout(app.PlanningTab);
            app.CoilDisplay = uiaxes(app.GridLayout2);
            xlabel(app.CoilDisplay, 'X')
            ylabel(app.CoilDisplay, 'Y')
            zlabel(app.CoilDisplay, 'Z')
            app.CrossSectionDisplay = uiaxes(app.GridLayout2);
            xlabel(app.CrossSectionDisplay, 'X')
            ylabel(app.CrossSectionDisplay, 'X')
            zlabel(app.CrossSectionDisplay, 'Z')
            app.CoilTransformationMatrixPanel = uipanel(app.GridLayout2);
            app.CoilTransformationMatrixPanel.Title = 'Coil Transformation Matrix';
            app.MatrixField21 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField21.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField31 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField31.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField41.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField41.Editable = 'off';
            app.MatrixField42 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField42.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField42.Editable = 'off';
            app.MatrixField11 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField11.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField11.Value = 1;
            app.MatrixField13 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField13.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField32 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField32.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField22.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField22.Value = 1;
            app.MatrixField12 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField12.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField14 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField14.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField23 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField23.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField43.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField43.Editable = 'off';
            app.MatrixField24 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField24.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField34 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField34.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField34.Value = 15;
            app.MatrixField33 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField33.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField33.Value = 1;
            app.MatrixField44 = uieditfield(app.CoilTransformationMatrixPanel, 'numeric');
            app.MatrixField44.ValueChangedFcn = createCallbackFcn(app, @MatrixFieldValueChanged, true);
            app.MatrixField44.Editable = 'off';
            app.MatrixField44.Value = 1;
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel = uipanel(app.GridLayout2);
            app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel.Title = 'Contributors: Alton Miles, Graham Ornstein, Kseniia Romanova';
            app.Versionv031alphaLabel = uilabel(app.ContributorsAltonMilesGrahamOrnsteinKseniiaRomanovaPanel);
            app.Versionv031alphaLabel.Text = 'Version: v0.3.1-alpha';
            app.VisualControlsPanel = uipanel(app.GridLayout2);
            app.VisualControlsPanel.Title = 'Visual Controls';
            app.FieldVectorSwitchLabel = uilabel(app.VisualControlsPanel);
            app.FieldVectorSwitchLabel.Text = 'Field Vector';
            app.FieldVectorSwitch = uiswitch(app.VisualControlsPanel, 'slider');
            app.FieldVectorSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.FieldVectorSwitch.Tooltip = {'Toggle a vector from the brain to the coil'};
            app.FieldVectorSwitch.Value = 'On';
            app.VectorfromcoilSwitchLabel = uilabel(app.VisualControlsPanel);
            app.VectorfromcoilSwitchLabel.Text = 'Vector from coil';
            app.VectorfromcoilSwitch = uiswitch(app.VisualControlsPanel, 'slider');
            app.VectorfromcoilSwitch.ValueChangedFcn = createCallbackFcn(app, @VectortocoilSwitchValueChanged, true);
            app.VectorfromcoilSwitch.Tooltip = {'Toggle a vector showing the coil''s direction'};
            app.VectorfromcoilSwitch.Value = 'On';
            app.HeadCompartmentsDropDownLabel = uilabel(app.VisualControlsPanel);
            app.HeadCompartmentsDropDownLabel.Text = 'Head Compartments';
            app.HeadCompartmentsDropDown = uidropdown(app.VisualControlsPanel);
            app.HeadCompartmentsDropDown.Items = {};
            app.HeadCompartmentsDropDown.ValueChangedFcn = createCallbackFcn(app, @HeadCompartmentsDropDownValueChanged, true);
            app.HeadCompartmentsDropDown.Value = {};
            app.ScalecmSliderLabel = uilabel(app.VisualControlsPanel);
            app.ScalecmSliderLabel.Text = 'Scale (cm)';
            app.ScalecmSlider = uislider(app.VisualControlsPanel);
            app.ScalecmSlider.Limits = [1 40];
            app.ScalecmSlider.MajorTicks = [1 10 20 30 40];
            app.ScalecmSlider.ValueChangedFcn = createCallbackFcn(app, @ScalecmSliderValueChanged, true);
            app.ScalecmSlider.MinorTicks = [5 15 25 35];
            app.ScalecmSlider.Tooltip = {'Change the axis size'};
            app.ScalecmSlider.Value = 15;
            app.NiftiDataDropDownLabel = uilabel(app.VisualControlsPanel);
            app.NiftiDataDropDownLabel.Text = 'Nifti Data';
            app.NiftiDataDropDown = uidropdown(app.VisualControlsPanel);
            app.NiftiDataDropDown.Items = {};
            app.NiftiDataDropDown.ValueChangedFcn = createCallbackFcn(app, @NiftiDataDropDownValueChanged, true);
            app.NiftiDataDropDown.Value = {};
            app.TransformationMatrixVisabilityLabel = uilabel(app.GridLayout2);
            app.TransformationMatrixVisabilityLabel.Text = 'Transformation Matrix Visability';
            app.TransformationMatrixSwitch = uiswitch(app.GridLayout2, 'slider');
            app.TransformationMatrixSwitch.ValueChangedFcn = createCallbackFcn(app, @TransformationMatrixSwitchValueChanged, true);
            app.TransformationMatrixSwitch.Value = 'On';
            app.CoilControlsTabGroup = uitabgroup(app.GridLayout2);
            app.AutomaticTab = uitab(app.CoilControlsTabGroup);
            app.AutomaticTab.Title = 'Automatic';
            app.ChosenTargetcmLabel = uilabel(app.AutomaticTab);
            app.ChosenTargetcmLabel.Text = 'Chosen Target (cm)';
            app.UpdateTargetButton = uibutton(app.AutomaticTab, 'push');
            app.UpdateTargetButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTargetButtonPushed, true);
            app.UpdateTargetButton.Tooltip = {'Overwrites the point in the program. Only do this for point on the GM layer'};
            app.UpdateTargetButton.Text = 'Update Target';
            app.PointXValEditField = uieditfield(app.AutomaticTab, 'numeric');
            app.PointXValEditField.ValueChangedFcn = createCallbackFcn(app, @PointXValEditFieldValueChanged, true);
            app.PointXValEditField.Tooltip = {'The targeted point''s x-coordinate'};
            app.PointYValEditField = uieditfield(app.AutomaticTab, 'numeric');
            app.PointYValEditField.ValueChangedFcn = createCallbackFcn(app, @PointXValEditFieldValueChanged, true);
            app.PointYValEditField.Tooltip = {'The targeted point''s y-coordinate'};
            app.PointZValEditField = uieditfield(app.AutomaticTab, 'numeric');
            app.PointZValEditField.ValueChangedFcn = createCallbackFcn(app, @PointXValEditFieldValueChanged, true);
            app.PointZValEditField.Tooltip = {'The targeted point''s z-coordinate'};
            app.UpdateDisplayButton = uibutton(app.AutomaticTab, 'push');
            app.UpdateDisplayButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDisplayButtonPushed, true);
            app.UpdateDisplayButton.Tooltip = {'Updates the display to reflect the point and the radius values'};
            app.UpdateDisplayButton.Text = 'Update Display';
            app.ResetRotationButton = uibutton(app.AutomaticTab, 'push');
            app.ResetRotationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton.Tooltip = {'Resets the rotation of the coil'};
            app.ResetRotationButton.Text = 'Reset Rotation';
            app.CoilAxisRotationpiradsSliderLabel = uilabel(app.AutomaticTab);
            app.CoilAxisRotationpiradsSliderLabel.Text = 'Coil-Axis Rotation (pi rads)';
            app.CoilAxisRotationpiradsSlider = uislider(app.AutomaticTab);
            app.CoilAxisRotationpiradsSlider.Limits = [-1 1];
            app.CoilAxisRotationpiradsSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.CoilAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.CoilAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @CoilAxisRotationpiradsSliderValueChanged, true);
            app.CoilAxisRotationpiradsSlider.MinorTicks = [-0.75 -0.25 0.25 0.75];
            app.CoilAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the coil''s axis'};
            app.mmFromSkinSliderLabel = uilabel(app.AutomaticTab);
            app.mmFromSkinSliderLabel.Text = 'mm From Skin';
            app.mmFromSkinSlider = uislider(app.AutomaticTab);
            app.mmFromSkinSlider.MajorTicks = [0 20 40 60 80 100];
            app.mmFromSkinSlider.MinorTicks = [5 10 15 25 30 35 45 50 55 65 70 75 85 90 95];
            app.mmFromSkinSlider.Tooltip = {'The distance from the skin for the coil'};
            app.ResetTranslationButton = uibutton(app.AutomaticTab, 'push');
            app.ResetTranslationButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTranslationButtonPushed, true);
            app.ResetTranslationButton.Tooltip = {'Resets the translation of the coil'};
            app.ResetTranslationButton.Text = 'Reset Translation';
            app.AddObservationPlanesButton = uibutton(app.AutomaticTab, 'push');
            app.AddObservationPlanesButton.ButtonPushedFcn = createCallbackFcn(app, @AddObservationPlanesButtonPushed, true);
            app.AddObservationPlanesButton.Text = 'Add Observation Planes';
            app.AddObservationLineButton = uibutton(app.AutomaticTab, 'push');
            app.AddObservationLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddObservationLineButtonPushed, true);
            app.AddObservationLineButton.Text = 'Add Observation Line';
            app.ManualTab = uitab(app.CoilControlsTabGroup);
            app.ManualTab.Title = 'Manual';
            app.XTranslationcmSliderLabel = uilabel(app.ManualTab);
            app.XTranslationcmSliderLabel.Tooltip = {'The x-component of the coil''s translation'};
            app.XTranslationcmSliderLabel.Text = 'X Translation (cm)';
            app.XTranslationcmSlider = uislider(app.ManualTab);
            app.XTranslationcmSlider.Limits = [-20 20];
            app.XTranslationcmSlider.MajorTicks = [-20 -10 0 10 20];
            app.XTranslationcmSlider.ValueChangedFcn = createCallbackFcn(app, @XTranslationcmSliderValueChanged, true);
            app.XTranslationcmSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.XTranslationcmSlider.Tooltip = {'Applies a translation to the coil along the x-axis'};
            app.YTranslationcmSliderLabel = uilabel(app.ManualTab);
            app.YTranslationcmSliderLabel.Tooltip = {'The y-component of the coil''s translation'};
            app.YTranslationcmSliderLabel.Text = 'Y Translation (cm)';
            app.YTranslationcmSlider = uislider(app.ManualTab);
            app.YTranslationcmSlider.Limits = [-20 20];
            app.YTranslationcmSlider.MajorTicks = [-20 -10 0 10 20];
            app.YTranslationcmSlider.ValueChangedFcn = createCallbackFcn(app, @YTranslationcmSliderValueChanged, true);
            app.YTranslationcmSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.YTranslationcmSlider.Tooltip = {'Applies a translation to the coil along the y-axis'};
            app.ZTranslationcmSliderLabel = uilabel(app.ManualTab);
            app.ZTranslationcmSliderLabel.Tooltip = {'The z-component of the coil''s translation'};
            app.ZTranslationcmSliderLabel.Text = 'Z Translation (cm)';
            app.ZTranslationcmSlider = uislider(app.ManualTab);
            app.ZTranslationcmSlider.Limits = [-20 20];
            app.ZTranslationcmSlider.MajorTicks = [-20 -10 0 10 20];
            app.ZTranslationcmSlider.ValueChangedFcn = createCallbackFcn(app, @ZTranslationcmSliderValueChanged, true);
            app.ZTranslationcmSlider.MinorTicks = [-17.5 -15 -12.5 -7.5 -5 -2.5 2.5 5 7.5 12.5 15 17.5];
            app.ZTranslationcmSlider.Tooltip = {'Applies a translation to the coil along the z-axis'};
            app.XAxisRotationpiradsSliderLabel = uilabel(app.ManualTab);
            app.XAxisRotationpiradsSliderLabel.Text = 'X-Axis Rotation (pi rads)';
            app.XAxisRotationpiradsSlider = uislider(app.ManualTab);
            app.XAxisRotationpiradsSlider.Limits = [-1 1];
            app.XAxisRotationpiradsSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.XAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.XAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @XAxisRotationpiradsSliderValueChanged, true);
            app.XAxisRotationpiradsSlider.MinorTicks = [-0.875 -0.75 -0.625 -0.375 -0.25 -0.125 0.125 0.25 0.375 0.625 0.75 0.875];
            app.XAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the x-axis'};
            app.YAxisRotationpiradsSliderLabel = uilabel(app.ManualTab);
            app.YAxisRotationpiradsSliderLabel.Text = 'Y-Axis Rotation (pi rads)';
            app.YAxisRotationpiradsSlider = uislider(app.ManualTab);
            app.YAxisRotationpiradsSlider.Limits = [-1 1];
            app.YAxisRotationpiradsSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.YAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.YAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @YAxisRotationpiradsSliderValueChanged, true);
            app.YAxisRotationpiradsSlider.MinorTicks = [-0.875 -0.625 -0.375 -0.125 0.125 0.375 0.625 0.875];
            app.YAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the y-axis'};
            app.ZAxisRotationpiradsSliderLabel = uilabel(app.ManualTab);
            app.ZAxisRotationpiradsSliderLabel.Text = 'Z-Axis Rotation (pi rads)';
            app.ZAxisRotationpiradsSlider = uislider(app.ManualTab);
            app.ZAxisRotationpiradsSlider.Limits = [-1 1];
            app.ZAxisRotationpiradsSlider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.ZAxisRotationpiradsSlider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.ZAxisRotationpiradsSlider.ValueChangedFcn = createCallbackFcn(app, @ZAxisRotationpiradsSliderValueChanged, true);
            app.ZAxisRotationpiradsSlider.MinorTicks = [-0.875 -0.75 -0.625 -0.375 -0.25 -0.125 0.125 0.25 0.375 0.625 0.75 0.875];
            app.ZAxisRotationpiradsSlider.Tooltip = {'Applies a rotation to the coil around the coil''s axis'};
            app.ResetRotationButton_2 = uibutton(app.ManualTab, 'push');
            app.ResetRotationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetRotationButtonPushed, true);
            app.ResetRotationButton_2.Tooltip = {'Resets the rotation of the coil'};
            app.ResetRotationButton_2.Text = 'Reset Rotation';
            app.ResetTranslationButton_2 = uibutton(app.ManualTab, 'push');
            app.ResetTranslationButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetTranslationButtonPushed, true);
            app.ResetTranslationButton_2.Tooltip = {'Resets the translation of the coil'};
            app.ResetTranslationButton_2.Text = 'Reset Translation';
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PlanesTab = uitab(app.TabGroup);
            app.PlanesTab.Title = 'Planes';
            app.PlanesDropDownLabel = uilabel(app.PlanesTab);
            app.PlanesDropDownLabel.Text = 'Planes';
            app.PlanesDropDown = uidropdown(app.PlanesTab);
            app.PlanesDropDown.Items = {};
            app.PlanesDropDown.ValueChangedFcn = createCallbackFcn(app, @PlanesDropDownValueChanged, true);
            app.PlanesDropDown.Value = {};
            app.XCoordcmSliderLabel = uilabel(app.PlanesTab);
            app.XCoordcmSliderLabel.Text = 'X Coord (cm)';
            app.XCoordcmSlider = uislider(app.PlanesTab);
            app.XCoordcmSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordcmSliderValueChanged, true);
            app.YCoordcmSliderLabel = uilabel(app.PlanesTab);
            app.YCoordcmSliderLabel.Text = 'Y Coord (cm)';
            app.YCoordcmSlider = uislider(app.PlanesTab);
            app.YCoordcmSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordcmSliderValueChanged, true);
            app.ZCoordcmSliderLabel = uilabel(app.PlanesTab);
            app.ZCoordcmSliderLabel.Text = 'Z Coord (cm)';
            app.ZCoordcmSlider = uislider(app.PlanesTab);
            app.ZCoordcmSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordcmSliderValueChanged, true);
            app.DirectionDropDownLabel = uilabel(app.PlanesTab);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.PlanesTab);
            app.DirectionDropDown.Items = {'xy', 'xz', 'yz'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @XCoordcmSliderValueChanged, true);
            app.DirectionDropDown.Value = 'xy';
            app.PlaneNameEditFieldLabel = uilabel(app.PlanesTab);
            app.PlaneNameEditFieldLabel.Text = 'Plane Name';
            app.PlaneNameEditField = uieditfield(app.PlanesTab, 'text');
            app.PlaneNameEditField.ValueChangedFcn = createCallbackFcn(app, @PlaneNameEditFieldValueChanged, true);
            app.CenterOnPointButton = uibutton(app.PlanesTab, 'push');
            app.CenterOnPointButton.ButtonPushedFcn = createCallbackFcn(app, @CenterOnPointButtonPushed, true);
            app.CenterOnPointButton.Text = 'Center On Point';
            app.PlaneWidthcmEditFieldLabel = uilabel(app.PlanesTab);
            app.PlaneWidthcmEditFieldLabel.Text = 'Plane Width (cm)';
            app.PlaneWidthcmEditField = uieditfield(app.PlanesTab, 'numeric');
            app.PlaneWidthcmEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordcmSliderValueChanged, true);
            app.PlaneWidthcmEditField.Value = 2;
            app.AddNewPlaneButton = uibutton(app.PlanesTab, 'push');
            app.AddNewPlaneButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewPlaneButtonPushed, true);
            app.AddNewPlaneButton.Text = 'Add New Plane';
            app.DeletePlaneButton = uibutton(app.PlanesTab, 'push');
            app.DeletePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @DeletePlaneButtonPushed, true);
            app.DeletePlaneButton.Text = 'Delete Plane';
            app.CoilDisplayVisibilitySwitchLabel = uilabel(app.PlanesTab);
            app.CoilDisplayVisibilitySwitchLabel.Text = 'Coil Display Visibility';
            app.CoilDisplayVisibilitySwitch = uiswitch(app.PlanesTab, 'slider');
            app.CoilDisplayVisibilitySwitch.ItemsData = [false true];
            app.CoilDisplayVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @CoilDisplayVisibilitySwitchValueChanged, true);
            app.CoilDisplayVisibilitySwitch.Value = true;
            app.LinesTab = uitab(app.TabGroup);
            app.LinesTab.Title = 'Lines';
            app.XCoordcmSlider_2Label = uilabel(app.LinesTab);
            app.XCoordcmSlider_2Label.Text = 'X Coord (cm)';
            app.XCoordLinecmSlider = uislider(app.LinesTab);
            app.XCoordLinecmSlider.Limits = [-15 15];
            app.XCoordLinecmSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmSliderValueChanged, true);
            app.YCoordcmSlider_2Label = uilabel(app.LinesTab);
            app.YCoordcmSlider_2Label.Text = 'Y Coord (cm)';
            app.YCoordLinecmSlider = uislider(app.LinesTab);
            app.YCoordLinecmSlider.Limits = [-15 15];
            app.YCoordLinecmSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmSliderValueChanged, true);
            app.ZCoordcmSlider_2Label = uilabel(app.LinesTab);
            app.ZCoordcmSlider_2Label.Text = 'Z Coord (cm)';
            app.ZCoordLinecmSlider = uislider(app.LinesTab);
            app.ZCoordLinecmSlider.Limits = [-15 15];
            app.ZCoordLinecmSlider.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmSliderValueChanged, true);
            app.LinesDropDownLabel = uilabel(app.LinesTab);
            app.LinesDropDownLabel.Text = 'Lines';
            app.LinesDropDown = uidropdown(app.LinesTab);
            app.LinesDropDown.Items = {};
            app.LinesDropDown.ValueChangedFcn = createCallbackFcn(app, @LinesDropDownValueChanged, true);
            app.LinesDropDown.Value = {};
            app.LineNameEditFieldLabel = uilabel(app.LinesTab);
            app.LineNameEditFieldLabel.Text = 'Line Name';
            app.LineNameEditField = uieditfield(app.LinesTab, 'text');
            app.LineNameEditField.ValueChangedFcn = createCallbackFcn(app, @LineNameEditFieldValueChanged, true);
            app.AddNewLineButton = uibutton(app.LinesTab, 'push');
            app.AddNewLineButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewLineButtonPushed, true);
            app.AddNewLineButton.Text = 'Add New Line';
            app.DeleteLineButton = uibutton(app.LinesTab, 'push');
            app.DeleteLineButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLineButtonPushed, true);
            app.DeleteLineButton.Text = 'Delete Line';
            app.CenterLineOnPointButton = uibutton(app.LinesTab, 'push');
            app.CenterLineOnPointButton.ButtonPushedFcn = createCallbackFcn(app, @CenterLineOnPointButtonPushed, true);
            app.CenterLineOnPointButton.Text = 'Center On Point';
            app.LineLengthcmEditFieldLabel = uilabel(app.LinesTab);
            app.LineLengthcmEditFieldLabel.Text = 'Line Length (cm)';
            app.LineLengthcmEditField = uieditfield(app.LinesTab, 'numeric');
            app.LineLengthcmEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmSliderValueChanged, true);
            app.LineLengthcmEditField.Value = 10;
            app.XVectorDirectionEditFieldLabel = uilabel(app.LinesTab);
            app.XVectorDirectionEditFieldLabel.Text = 'X Vector Direction';
            app.XVectorDirectionEditField = uieditfield(app.LinesTab, 'numeric');
            app.XVectorDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmSliderValueChanged, true);
            app.YVectorDirectionEditFieldLabel = uilabel(app.LinesTab);
            app.YVectorDirectionEditFieldLabel.Text = 'Y Vector Direction';
            app.YVectorDirectionEditField = uieditfield(app.LinesTab, 'numeric');
            app.YVectorDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmSliderValueChanged, true);
            app.ZVectorDirectionEditFieldLabel = uilabel(app.LinesTab);
            app.ZVectorDirectionEditFieldLabel.Text = 'Z Vector Direction';
            app.ZVectorDirectionEditField = uieditfield(app.LinesTab, 'numeric');
            app.ZVectorDirectionEditField.ValueChangedFcn = createCallbackFcn(app, @XCoordLinecmSliderValueChanged, true);
            app.CoilDisplayVisibilitySwitch_2Label = uilabel(app.LinesTab);
            app.CoilDisplayVisibilitySwitch_2Label.Text = 'Coil Display Visibility';
            app.CoilDisplayLineVisibilitySwitch = uiswitch(app.LinesTab, 'slider');
            app.CoilDisplayLineVisibilitySwitch.ItemsData = [false true];
            app.CoilDisplayLineVisibilitySwitch.ValueChangedFcn = createCallbackFcn(app, @CoilDisplayLineVisibilitySwitchValueChanged, true);
            app.CoilDisplayLineVisibilitySwitch.Value = true;
            app.ProcessingTab = uitab(app.WindowTabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.GridLayout4 = uigridlayout(app.ProcessingTab);
            app.NumberofcoresforprocessingLabel = uilabel(app.GridLayout4);
            app.NumberofcoresforprocessingLabel.Text = 'Cores for Processing';
            app.NumberOfThreadsForProcessingEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumberOfThreadsForProcessingEditField.Limits = [0 128];
            app.NumberOfThreadsForProcessingEditField.RoundFractionalValues = 'on';
            app.NumberOfThreadsForProcessingEditField.ValueDisplayFormat = '%11.4g Cores';
            app.NumberOfThreadsForProcessingEditField.Tooltip = {'Do not exceed available CPU core count'};
            app.NumberOfThreadsForProcessingEditField.Value = 8;
            app.StartProcessingButton = uibutton(app.GridLayout4, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Tooltip = {'Press once to start processing (this will take a few minutes)'};
            app.StartProcessingButton.Text = 'Start Processing';
            app.CoilCurrentEditFieldLabel = uilabel(app.GridLayout4);
            app.CoilCurrentEditFieldLabel.Text = 'Coil Current';
            app.CoilCurrentEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CoilCurrentEditField.Limits = [0 Inf];
            app.CoilCurrentEditField.ValueDisplayFormat = '%11.4gA';
            app.CoilCurrentEditField.Tooltip = {'Enter coil current in amperes'};
            app.CoilCurrentEditField.Value = 5000;
            app.MaximumIterationsEditFieldLabel = uilabel(app.GridLayout4);
            app.MaximumIterationsEditFieldLabel.Text = 'Maximum Iterations';
            app.MaximumIterationsEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MaximumIterationsEditField.Limits = [0 Inf];
            app.MaximumIterationsEditField.RoundFractionalValues = 'on';
            app.MaximumIterationsEditField.ValueDisplayFormat = '%11.0f Iterations';
            app.MaximumIterationsEditField.Tooltip = {'This is the maximum number of iterations that the FMM solver will run (higher number = more accurate, but not by a lot after a certain point)'};
            app.MaximumIterationsEditField.Value = 25;
            app.SolverStopCriteriaLabel = uilabel(app.GridLayout4);
            app.SolverStopCriteriaLabel.Text = 'Solver Stop Criteria';
            app.GeneralOptionsLabel = uilabel(app.GridLayout4);
            app.GeneralOptionsLabel.Text = 'General Options';
            app.MinimumResidualEditFieldLabel = uilabel(app.GridLayout4);
            app.MinimumResidualEditFieldLabel.Text = 'Minimum Residual';
            app.MinimumResidualEditField = uieditfield(app.GridLayout4, 'numeric');
            app.MinimumResidualEditField.Value = 1e-12;
            app.LampStartProcessing = uilamp(app.GridLayout4);
            app.LampStartProcessing.Tooltip = {'Processing Indicator'};
            app.ExportingTab = uitab(app.WindowTabGroup);
            app.ExportingTab.Title = 'Exporting';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end